classdef realtimeer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveRecommendationDataDefaultMenu  matlab.ui.container.Menu
        SaveRecommendationDataSpecifiedMenu  matlab.ui.container.Menu
        SaveTradingDataDefaultMenu      matlab.ui.container.Menu
        SaveTradingDataSpecifiedMenu    matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        LoadRecommendationDataDefaultMenu  matlab.ui.container.Menu
        LoadRecommendationDataSpecifiedMenu  matlab.ui.container.Menu
        LoadTradingDataDefaultMenu      matlab.ui.container.Menu
        LoadTradingDataSpecifiedMenu    matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        T1TradingRealMenu               matlab.ui.container.Menu
        PriceBacthUpdateMenu            matlab.ui.container.Menu
        RollBackOneDayDebugModeMenu     matlab.ui.container.Menu
        RecommendationGroupReceptivenessMenu  matlab.ui.container.Menu
        SuggestionMenu                  matlab.ui.container.Menu
        DeprecationMenu                 matlab.ui.container.Menu
        NeutralMenu                     matlab.ui.container.Menu
        ControlRandomMenu               matlab.ui.container.Menu
        DiscardRecommendationListMenu   matlab.ui.container.Menu
        AutoSaveRecommendationListMenu  matlab.ui.container.Menu
        LastAutoSaveMenu                matlab.ui.container.Menu
        LanguagesMenu                   matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        ChineseMenu                     matlab.ui.container.Menu
        JapaneseMenu                    matlab.ui.container.Menu
        RussianMenu                     matlab.ui.container.Menu
        ScriptMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        DevelopersMenu                  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        RealTimeMonitorTab              matlab.ui.container.Tab
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        Lamp_1                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_10                         matlab.ui.control.Lamp
        NameLabel_1                     matlab.ui.control.Label
        NameLabel_2                     matlab.ui.control.Label
        NameLabel_3                     matlab.ui.control.Label
        NameLabel_4                     matlab.ui.control.Label
        NameLabel_5                     matlab.ui.control.Label
        NameLabel_6                     matlab.ui.control.Label
        NameLabel_7                     matlab.ui.control.Label
        NameLabel_8                     matlab.ui.control.Label
        NameLabel_9                     matlab.ui.control.Label
        NameLabel_10                    matlab.ui.control.Label
        DateLabel_1                     matlab.ui.control.Label
        DateLabel_2                     matlab.ui.control.Label
        DateLabel_3                     matlab.ui.control.Label
        DateLabel_4                     matlab.ui.control.Label
        DateLabel_5                     matlab.ui.control.Label
        DateLabel_6                     matlab.ui.control.Label
        DateLabel_7                     matlab.ui.control.Label
        DateLabel_8                     matlab.ui.control.Label
        DateLabel_9                     matlab.ui.control.Label
        DateLabel_10                    matlab.ui.control.Label
        CodeLabel_1                     matlab.ui.control.Label
        CodeLabel_2                     matlab.ui.control.Label
        CodeLabel_3                     matlab.ui.control.Label
        CodeLabel_4                     matlab.ui.control.Label
        CodeLabel_5                     matlab.ui.control.Label
        CodeLabel_6                     matlab.ui.control.Label
        CodeLabel_7                     matlab.ui.control.Label
        CodeLabel_8                     matlab.ui.control.Label
        CodeLabel_9                     matlab.ui.control.Label
        CodeLabel_10                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RealTimeButton                  matlab.ui.control.StateButton
        PredictionButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RePredCheckBox                  matlab.ui.control.CheckBox
        Progressbar_2                   matlab.ui.control.Label
        Progressbar                     matlab.ui.control.Label
        PredCodeLabel_1                 matlab.ui.control.Label
        PredCodeLabel_2                 matlab.ui.control.Label
        PredCodeLabel_3                 matlab.ui.control.Label
        PredCodeLabel_4                 matlab.ui.control.Label
        PredCodeLabel_5                 matlab.ui.control.Label
        PredCodeLabel_6                 matlab.ui.control.Label
        PredCodeLabel_7                 matlab.ui.control.Label
        PredCodeLabel_8                 matlab.ui.control.Label
        PredCodeLabel_9                 matlab.ui.control.Label
        PredCodeLabel_10                matlab.ui.control.Label
        RTimeLabel_1                    matlab.ui.control.Label
        RTimeLabel_2                    matlab.ui.control.Label
        RTimeLabel_3                    matlab.ui.control.Label
        RTimeLabel_4                    matlab.ui.control.Label
        RTimeLabel_5                    matlab.ui.control.Label
        RTimeLabel_6                    matlab.ui.control.Label
        RTimeLabel_7                    matlab.ui.control.Label
        RTimeLabel_8                    matlab.ui.control.Label
        RTimeLabel_9                    matlab.ui.control.Label
        RTimeLabel_10                   matlab.ui.control.Label
        RTLamp_1                        matlab.ui.control.Lamp
        RTLamp_2                        matlab.ui.control.Lamp
        RTLamp_3                        matlab.ui.control.Lamp
        RTLamp_4                        matlab.ui.control.Lamp
        RTLamp_5                        matlab.ui.control.Lamp
        RTLamp_6                        matlab.ui.control.Lamp
        RTLamp_7                        matlab.ui.control.Lamp
        RTLamp_8                        matlab.ui.control.Lamp
        RTLamp_9                        matlab.ui.control.Lamp
        RTLamp_10                       matlab.ui.control.Lamp
        StockListTab                    matlab.ui.container.Tab
        StockList                       matlab.ui.control.ListBox
        AddCSButton                     matlab.ui.control.Button
        CSList                          matlab.ui.control.ListBox
        RemoveCSButton                  matlab.ui.control.Button
        KMSList                         matlab.ui.control.ListBox
        AddKMSButton                    matlab.ui.control.Button
        RemoveKMSButton                 matlab.ui.control.Button
        SearchField                     matlab.ui.control.EditField
        SearchButton                    matlab.ui.control.Button
        CSLabel                         matlab.ui.control.Label
        KMSLabel                        matlab.ui.control.Label
        KlineShower                     matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.Button
        InPoolButton                    matlab.ui.control.Button
        ALLButton                       matlab.ui.control.Button
        ClearKMSButton                  matlab.ui.control.Button
        ClearCSButton                   matlab.ui.control.Button
        ShuffleButton                   matlab.ui.control.Button
        RecommendationSystemTab         matlab.ui.container.Tab
        StockPoolList                   matlab.ui.control.ListBox
        StockPoolLabel                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ProgressLabel2                  matlab.ui.control.Label
        Progressbar2                    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        ResetButtonRecommender          matlab.ui.control.Button
        RecommandTableGroup             matlab.ui.container.TabGroup
        SuggestionTab                   matlab.ui.container.Tab
        SuggestionTable                 matlab.ui.control.Table
        NLabel                          matlab.ui.control.Label
        SugNLabel                       matlab.ui.control.Label
        DeprecationTab                  matlab.ui.container.Tab
        DeprecationTable                matlab.ui.control.Table
        NLabel_2                        matlab.ui.control.Label
        DepNLabel                       matlab.ui.control.Label
        NeutralTab                      matlab.ui.container.Tab
        NeutralTable                    matlab.ui.control.Table
        NLabel_3                        matlab.ui.control.Label
        NeuNLabel                       matlab.ui.control.Label
        ControlRandomTab                matlab.ui.container.Tab
        ControlTable                    matlab.ui.control.Table
        NLabel_4                        matlab.ui.control.Label
        ConNLabel                       matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        AddRecommandTableButton         matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        TradeSimulationSystemTab        matlab.ui.container.Tab
        InOutTabGroup                   matlab.ui.container.TabGroup
        PurchaseInTab                   matlab.ui.container.Tab
        AddFromRSButton                 matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PurchaseButton                  matlab.ui.control.Button
        BuyTable                        matlab.ui.control.Table
        AddCandidatesButton             matlab.ui.control.Button
        SellOutTab                      matlab.ui.container.Tab
        SellTable                       matlab.ui.control.Table
        SellButton                      matlab.ui.control.Button
        AutoSellButton                  matlab.ui.control.StateButton
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        SumTabGroup                     matlab.ui.container.TabGroup
        SuggestionTabSum                matlab.ui.container.Tab
        SumTableSuggestion              matlab.ui.control.Table
        Label99                         matlab.ui.control.Label
        IncreaseLabelSuggestion         matlab.ui.control.Label
        IncreaseRateLabelSuggestion     matlab.ui.control.Label
        TotalSumEditFieldLabel          matlab.ui.control.Label
        TotalSumEditFieldSuggestion     matlab.ui.control.NumericEditField
        AccountSumEditFieldLabel        matlab.ui.control.Label
        AccountSumEditFieldSuggestion   matlab.ui.control.NumericEditField
        InvestSumEditFieldLabel         matlab.ui.control.Label
        InvestSumEditFieldSuggestion    matlab.ui.control.NumericEditField
        ReturnSumEditFieldLabel         matlab.ui.control.Label
        ReturnSumEditFieldSuggestion    matlab.ui.control.NumericEditField
        TotalIncomeEditFieldLabel       matlab.ui.control.Label
        TotalIncomeEditFieldSuggestion  matlab.ui.control.NumericEditField
        InitialSumEditFieldLabel        matlab.ui.control.Label
        InitialSumEditFieldSuggestion   matlab.ui.control.NumericEditField
        GlobalPvalue_4                  matlab.ui.control.Label
        vsControlPValue_4               matlab.ui.control.Label
        GloballyLabel_6                 matlab.ui.control.Label
        vsControlLabel_6                matlab.ui.control.Label
        DeprecationTabSum               matlab.ui.container.Tab
        SumTableDeprecation             matlab.ui.control.Table
        Label99_2                       matlab.ui.control.Label
        IncreaseLabelDeprecation        matlab.ui.control.Label
        IncreaseRateLabelDeprecation    matlab.ui.control.Label
        AccountSumEditFieldDeprecation  matlab.ui.control.NumericEditField
        TotalSumEditField_2Label        matlab.ui.control.Label
        TotalSumEditFieldDeprecation    matlab.ui.control.NumericEditField
        InvestSumEditField_2Label       matlab.ui.control.Label
        InvestSumEditFieldDeprecation   matlab.ui.control.NumericEditField
        ReturnSumEditField_2Label       matlab.ui.control.Label
        ReturnSumEditFieldDeprecation   matlab.ui.control.NumericEditField
        TotalIncomeEditField_2Label     matlab.ui.control.Label
        TotalIncomeEditFieldDeprecation  matlab.ui.control.NumericEditField
        InitialSumEditField_2Label      matlab.ui.control.Label
        InitialSumEditFieldDeprecation  matlab.ui.control.NumericEditField
        AccountSumEditFieldLabel_2      matlab.ui.control.Label
        GlobalPvalue_3                  matlab.ui.control.Label
        vsControlPValue_3               matlab.ui.control.Label
        GloballyLabel_5                 matlab.ui.control.Label
        vsControlLabel_5                matlab.ui.control.Label
        NeutralTabSum                   matlab.ui.container.Tab
        SumTableNeutral                 matlab.ui.control.Table
        Label99_4                       matlab.ui.control.Label
        IncreaseLabelNeutral            matlab.ui.control.Label
        IncreaseRateLabelNeutral        matlab.ui.control.Label
        AccountSumEditFieldNeutral      matlab.ui.control.NumericEditField
        TotalSumEditField_4Label        matlab.ui.control.Label
        TotalSumEditFieldNeutral        matlab.ui.control.NumericEditField
        InvestSumEditField_4Label       matlab.ui.control.Label
        InvestSumEditFieldNeutral       matlab.ui.control.NumericEditField
        ReturnSumEditField_4Label       matlab.ui.control.Label
        ReturnSumEditFieldNeutral       matlab.ui.control.NumericEditField
        TotalIncomeEditField_4Label     matlab.ui.control.Label
        TotalIncomeEditFieldNeutral     matlab.ui.control.NumericEditField
        InitialSumEditField_4Label      matlab.ui.control.Label
        InitialSumEditFieldNeutral      matlab.ui.control.NumericEditField
        AccountSumEditFieldLabel_4      matlab.ui.control.Label
        GloballyLabel_2                 matlab.ui.control.Label
        vsControlLabel_2                matlab.ui.control.Label
        GlobalPvalue_2                  matlab.ui.control.Label
        vsControlPValue_2               matlab.ui.control.Label
        ControlRandomTabSum             matlab.ui.container.Tab
        SumTableControlRandom           matlab.ui.control.Table
        Label99_3                       matlab.ui.control.Label
        IncreaseLabelControlRandom      matlab.ui.control.Label
        IncreaseRateLabelControlRandom  matlab.ui.control.Label
        AccountSumEditFieldControlRandom  matlab.ui.control.NumericEditField
        TotalSumEditField_3Label        matlab.ui.control.Label
        TotalSumEditFieldControlRandom  matlab.ui.control.NumericEditField
        InvestSumEditField_3Label       matlab.ui.control.Label
        InvestSumEditFieldControlRandom  matlab.ui.control.NumericEditField
        ReturnSumEditField_3Label       matlab.ui.control.Label
        ReturnSumEditFieldControlRandom  matlab.ui.control.NumericEditField
        TotalIncomeEditField_3Label     matlab.ui.control.Label
        TotalIncomeEditFieldControlRandom  matlab.ui.control.NumericEditField
        InitialSumEditField_3Label      matlab.ui.control.Label
        InitialSumEditFieldControlRandom  matlab.ui.control.NumericEditField
        AccountSumEditFieldLabel_3      matlab.ui.control.Label
        GloballyLabel                   matlab.ui.control.Label
        vsControlLabel                  matlab.ui.control.Label
        GlobalPvalue                    matlab.ui.control.Label
        vsControlPValue                 matlab.ui.control.Label
        StatisticalAnalysisTab          matlab.ui.container.Tab
        AmpAxes                         matlab.ui.control.UIAxes
        RatioAxes                       matlab.ui.control.UIAxes
        AmpStaArea                      matlab.ui.control.TextArea
        ProStaArea                      matlab.ui.control.TextArea
        HitStaAxes                      matlab.ui.control.UIAxes
        EarnStaAxes                     matlab.ui.control.UIAxes
        HitStaArea                      matlab.ui.control.TextArea
        EarnStaArea                     matlab.ui.control.TextArea
        MonitorPanel                    matlab.ui.container.Panel
        TradeMonitorSwitchLabel         matlab.ui.control.Label
        TradeMonitorSwitch              matlab.ui.control.Switch
        ProgressLabel3                  matlab.ui.control.Label
        PendingLabel                    matlab.ui.control.Label
        DesginedandProgrammedbyZhangHengLabel  matlab.ui.control.Label
        ResetButtonMonitor              matlab.ui.control.Button
        UnLockButton                    matlab.ui.control.StateButton
        Label_4                         matlab.ui.control.Label
        Progressbar3                    matlab.ui.control.Label
    end


    properties (Access = private)
        hand % Description
        sum % Description
    end

    methods (Access = private)

        function setProgressbar(app,val)
            % 设置进度条进度值 
            progress = ceil(653 * val);
            position = get(app.Progressbar,'position');
            position(3) = progress;
            set(app.Progressbar,'position',position);
            set(app.Label,'text',[num2str(round(val*100)) ' %']);
            drawnow();
        end
        
        function callback(app,traininfo)
            app.setProgressbar(traininfo.Epoch / 500);
        end
        
        function initshow(app,index)
            show = app.getshow(index);
            hold(show.ax,'on');
            show.line1 = line(show.ax,[0 2],[10 10],'linewidth',2);
            show.line2 = line(show.ax,[0 2],[20 20],'linewidth',2);
            show.mark = scatter(show.ax,1,15,'r','linewidth',2);
            show.rect = rectangle(show.ax,'position',[0.5 10 1 10]);
            set(show.ax,'linewidth',1,'xtick',[],'box','on');
            hold(show.ax,'off');
            % 句柄只能访问不能修改
            app.setshow(show,index);
        end
        
        function res = getshow(app,index)
            switch index
                case 1
                    app.hand.show.show1.ax = app.UIAxes_1;
                    app.hand.show.show1.nameLabel = app.NameLabel_1;
                    app.hand.show.show1.codeLabel = app.CodeLabel_1;
                    app.hand.show.show1.dateLabel = app.DateLabel_1;
                    app.hand.show.show1.lamp = app.Lamp_1;
                    app.hand.show.show1.predCodeLabel = app.PredCodeLabel_1;
                    app.hand.show.show1.realTimeLabel = app.RTimeLabel_1;
                    app.hand.show.show1.rtlamp = app.RTLamp_1;
                    res = app.hand.show.show1;
                case 2
                    app.hand.show.show2.ax = app.UIAxes_2;
                    app.hand.show.show2.nameLabel = app.NameLabel_2;
                    app.hand.show.show2.codeLabel = app.CodeLabel_2;
                    app.hand.show.show2.dateLabel = app.DateLabel_2;
                    app.hand.show.show2.lamp = app.Lamp_2;
                    app.hand.show.show2.predCodeLabel = app.PredCodeLabel_2;
                    app.hand.show.show2.realTimeLabel = app.RTimeLabel_2;
                    app.hand.show.show2.rtlamp = app.RTLamp_2;
                    res = app.hand.show.show2;
                case 3
                    app.hand.show.show3.ax = app.UIAxes_3;
                    app.hand.show.show3.nameLabel = app.NameLabel_3;
                    app.hand.show.show3.codeLabel = app.CodeLabel_3;
                    app.hand.show.show3.dateLabel = app.DateLabel_3;
                    app.hand.show.show3.lamp = app.Lamp_3;
                    app.hand.show.show3.predCodeLabel = app.PredCodeLabel_3;
                    app.hand.show.show3.realTimeLabel = app.RTimeLabel_3;
                    app.hand.show.show3.rtlamp = app.RTLamp_3;
                    res = app.hand.show.show3;
                case 4
                    app.hand.show.show4.ax = app.UIAxes_4;
                    app.hand.show.show4.nameLabel = app.NameLabel_4;
                    app.hand.show.show4.codeLabel = app.CodeLabel_4;
                    app.hand.show.show4.dateLabel = app.DateLabel_4;
                    app.hand.show.show4.lamp = app.Lamp_4;
                    app.hand.show.show4.predCodeLabel = app.PredCodeLabel_4;
                    app.hand.show.show4.realTimeLabel = app.RTimeLabel_4;
                    app.hand.show.show4.rtlamp = app.RTLamp_4;
                    res = app.hand.show.show4;
                case 5
                    app.hand.show.show5.ax = app.UIAxes_5;
                    app.hand.show.show5.nameLabel = app.NameLabel_5;
                    app.hand.show.show5.codeLabel = app.CodeLabel_5;
                    app.hand.show.show5.dateLabel = app.DateLabel_5;
                    app.hand.show.show5.lamp = app.Lamp_5;
                    app.hand.show.show5.predCodeLabel = app.PredCodeLabel_5;
                    app.hand.show.show5.realTimeLabel = app.RTimeLabel_5;
                    app.hand.show.show5.rtlamp = app.RTLamp_5;
                    res = app.hand.show.show5;
                case 6
                    app.hand.show.show6.ax = app.UIAxes_6;
                    app.hand.show.show6.nameLabel = app.NameLabel_6;
                    app.hand.show.show6.codeLabel = app.CodeLabel_6;
                    app.hand.show.show6.dateLabel = app.DateLabel_6;
                    app.hand.show.show6.lamp = app.Lamp_6;
                    app.hand.show.show6.predCodeLabel = app.PredCodeLabel_6;
                    app.hand.show.show6.realTimeLabel = app.RTimeLabel_6;
                    app.hand.show.show6.rtlamp = app.RTLamp_6;
                    res = app.hand.show.show6;
                case 7
                    app.hand.show.show7.ax = app.UIAxes_7;
                    app.hand.show.show7.nameLabel = app.NameLabel_7;
                    app.hand.show.show7.codeLabel = app.CodeLabel_7;
                    app.hand.show.show7.dateLabel = app.DateLabel_7;
                    app.hand.show.show7.lamp = app.Lamp_7;
                    app.hand.show.show7.predCodeLabel = app.PredCodeLabel_7;
                    app.hand.show.show7.realTimeLabel = app.RTimeLabel_7;
                    app.hand.show.show7.rtlamp = app.RTLamp_7;
                    res = app.hand.show.show7;
                case 8
                    app.hand.show.show8.ax = app.UIAxes_8;
                    app.hand.show.show8.nameLabel = app.NameLabel_8;
                    app.hand.show.show8.codeLabel = app.CodeLabel_8;
                    app.hand.show.show8.dateLabel = app.DateLabel_8;
                    app.hand.show.show8.lamp = app.Lamp_8;
                    app.hand.show.show8.predCodeLabel = app.PredCodeLabel_8;
                    app.hand.show.show8.realTimeLabel = app.RTimeLabel_8;
                    app.hand.show.show8.rtlamp = app.RTLamp_8;
                    res = app.hand.show.show8;
                case 9
                    app.hand.show.show9.ax = app.UIAxes_9;
                    app.hand.show.show9.nameLabel = app.NameLabel_9;
                    app.hand.show.show9.codeLabel = app.CodeLabel_9;
                    app.hand.show.show9.dateLabel = app.DateLabel_9;
                    app.hand.show.show9.lamp = app.Lamp_9;
                    app.hand.show.show9.predCodeLabel = app.PredCodeLabel_9;
                    app.hand.show.show9.realTimeLabel = app.RTimeLabel_9;
                    app.hand.show.show9.rtlamp = app.RTLamp_9;
                    res = app.hand.show.show9;
                case 10
                    app.hand.show.show10.ax = app.UIAxes_10;
                    app.hand.show.show10.nameLabel = app.NameLabel_10;
                    app.hand.show.show10.codeLabel = app.CodeLabel_10;
                    app.hand.show.show10.dateLabel = app.DateLabel_10;
                    app.hand.show.show10.lamp = app.Lamp_10;
                    app.hand.show.show10.predCodeLabel = app.PredCodeLabel_10;
                    app.hand.show.show10.realTimeLabel = app.RTimeLabel_10;
                    app.hand.show.show10.rtlamp = app.RTLamp_10;
                    res = app.hand.show.show10;
            end
        end
        
        function setshow(app,show,index)
            switch index
                case 1
                    app.hand.show.show1 = show;
                case 2
                    app.hand.show.show2 = show;
                case 3
                    app.hand.show.show3 = show;
                case 4
                    app.hand.show.show4 = show;
                case 5
                    app.hand.show.show5 = show;
                case 6
                    app.hand.show.show6 = show;
                case 7
                    app.hand.show.show7 = show;
                case 8
                    app.hand.show.show8 = show;
                case 9
                    app.hand.show.show9 = show;
                case 10
                    app.hand.show.show10 = show;
            end 
        end
        
        function putinlist(app,list,stockcode)
            itemsdata = [];
            items = {};
            for i = 1 : numel(stockcode)
                index = find(strcmp(app.hand.stocklist.code,stockcode{i}));
                index = index(1);
                if isempty(index)
                    itemsdata = [itemsdata -i];
                    items = [items ['[' stockcode{i} ']  ' '????????' ' (' '??' ')  < ' '????????' ' >' ]];
                else
                    itemsdata = [itemsdata index];
                    items = [items ['[' app.hand.stocklist.code{index} ']  ' app.hand.stocklist.name{index} ' (' app.hand.stocklist.shsz{index} ')  < ' app.hand.stocklist.industry{index} ' >' ]];
                end
            end
            if strcmp(list,'kms')
                set(app.KMSList,'items',items);
                set(app.KMSList,'itemsdata',itemsdata);
                app.hand.kmslist.items = items;
                app.hand.kmslist.itemsdata = itemsdata;
            elseif strcmp(list,'cs')
                set(app.CSList,'items',items);
                set(app.CSList,'itemsdata',itemsdata);
                app.hand.cslist.items = items;
                app.hand.cslist.itemsdata = itemsdata;
            end
            
        end
        
        function setProgressbar2(app,val)
             % 设置进度条进度值 
            progress = ceil(125 * val);
            position = get(app.Progressbar2,'position');
            position(3) = progress;
            set(app.Progressbar2,'position',position);
            set(app.ProgressLabel2,'text',[num2str(round(val*100)) ' %']);
            drawnow();
        end
        
        function setProgressbar3(app,val)
             % 设置进度条进度值 
            progress = ceil(125 * val);
            position = get(app.Progressbar3,'position');
            position(3) = progress;
            set(app.Progressbar3,'position',position);
            set(app.ProgressLabel3,'text',[num2str(round(val*100)) ' %']);
            drawnow();
        end
        
        function callback2(app,traininfo)
            try
                app.setProgressbar2(traininfo.Epoch / 500);
            catch
            end
        end
        
        function updatesum(app)
            set(app.TotalSumEditFieldSuggestion,'value',app.sum.suggestion.total);
            set(app.TotalIncomeEditFieldSuggestion,'value',app.sum.suggestion.income);
            set(app.InvestSumEditFieldSuggestion,'value',app.sum.suggestion.invest);
            set(app.ReturnSumEditFieldSuggestion,'value',app.sum.suggestion.return);
            set(app.InitialSumEditFieldSuggestion,'value',app.sum.suggestion.initial);
            set(app.AccountSumEditFieldSuggestion,'value',app.sum.suggestion.account);
            
            set(app.TotalSumEditFieldDeprecation,'value',app.sum.deprecation.total);
            set(app.TotalIncomeEditFieldDeprecation,'value',app.sum.deprecation.income);
            set(app.InvestSumEditFieldDeprecation,'value',app.sum.deprecation.invest);
            set(app.ReturnSumEditFieldDeprecation,'value',app.sum.deprecation.return);
            set(app.InitialSumEditFieldDeprecation,'value',app.sum.deprecation.initial);
            set(app.AccountSumEditFieldDeprecation,'value',app.sum.deprecation.account);
            
            set(app.TotalSumEditFieldNeutral,'value',app.sum.neutral.total);
            set(app.TotalIncomeEditFieldNeutral,'value',app.sum.neutral.income);
            set(app.InvestSumEditFieldNeutral,'value',app.sum.neutral.invest);
            set(app.ReturnSumEditFieldNeutral,'value',app.sum.neutral.return);
            set(app.InitialSumEditFieldNeutral,'value',app.sum.neutral.initial);
            set(app.AccountSumEditFieldNeutral,'value',app.sum.neutral.account);
            
            set(app.TotalSumEditFieldControlRandom,'value',app.sum.control.total);
            set(app.TotalIncomeEditFieldControlRandom,'value',app.sum.control.income);
            set(app.InvestSumEditFieldControlRandom,'value',app.sum.control.invest);
            set(app.ReturnSumEditFieldControlRandom,'value',app.sum.control.return);
            set(app.InitialSumEditFieldControlRandom,'value',app.sum.control.initial);
            set(app.AccountSumEditFieldControlRandom,'value',app.sum.control.account);
        end
        
        function syncdata(app)
            thandle = app.getTabHandle();
            if isempty(thandle)
                return;
            end
            
            sumtable = get(thandle.table,'data');
            if isempty(sumtable)
                set(app.SellTable,'data',{});
                return;
            end
            ps = cell2mat(sumtable(:,[5 8]));
            if isempty(ps)
                set(app.SellTable,'data',{});
                return;
            end
            amp = (ps(:,1)-ps(:,2))*100./ps(:,2);
            temptable = [sumtable(:,[1:5 8]) num2cell(amp) sumtable(:,13)];
            selltable = {};
            for i = 1 : numel(temptable,1)
                if ~strcmp(app.T1TradingRealMenu.Checked,'on') || ~strcmp(char(datetime(sumtable{i,9},'format','yyyyMMdd')),char(datetime('now','format','yyyyMMdd')))
                    selltable =[selltable; temptable];
                end
            end
            selltable = [selltable num2cell(ones(size(selltable,1),1)) repmat({'√'},size(selltable,1),1)];
            set(app.SellTable,'data',selltable);
            
            if get(app.AutoSellButton,'value')
                thamp = get(app.ThresholdEditField,'value');
                indexs = find(amp > thamp);
                if isempty(indexs)
                    return;
                end
                tosell = selltable(indexs,:);
                returnsum = 0;
                for i = 1 : size(tosell,1)
                    serial = tosell{i,1};
                    price = tosell{i,5};
                    bill = tosell{i,9};
                    returnsum = returnsum + price * bill * 100;
                    
                    serindex = find(strcmp(sumtable(:,1),serial));
                    sumtable{serindex,13} = sumtable{serindex,13} - bill;
                end
                keepindex = [];
                for i = 1 : size(sumtable,1)
                    if sumtable{i,13} > 0
                        keepindex = [keepindex i];
                    end
                end
                sumtable = sumtable(keepindex,:);
                set(thandle.table,'data',sumtable);
                
                thandle.sum.return = thandle.sum.return + returnsum;
                thandle.sum.account = thandle.sum.account + returnsum;
                    
                app.setTabHandle(thandle.tabname,thandle.sum);
                
                app.updatesum();
                
                app.syncdata();
            end
        end
        
        function localize(app,tabname,sumtable,sum)
            switch tabname
                case {'Suggestion','建议买入'}
                    save('src/suggestion_sum.mat','sumtable','sum');
                case {'Deprecation','建议不买入'}
                    save('src/deprecation_sum.mat','sumtable','sum');
                case {'Neutral','中性'}
                    save('src/neutral_sum.mat','sumtable','sum');
                case {'Control (Random)','随机对照'}
                    save('src/control_sum.mat','sumtable','sum');
                
            end
        end
        
        function funcback(app,total,current)
            app.setProgressbar(current / total);
        end
        
        function funcback2(app,total,current)
             try
                app.setProgressbar2(current / total);
            catch
            end
        end
        
        
        function res = getTabHandle(app,varargin)
            if ~isempty(varargin)
                stab = varargin{1};
            else
                stab = app.SumTabGroup.SelectedTab.Title;
            end
            res.tabname = stab;
            switch stab
                case {'Suggestion','建议买入'}
                    res.table = app.SumTableSuggestion;
                    res.increaseLabel = app.IncreaseLabelSuggestion;
                    res.increaseRateLabel = app.IncreaseRateLabelSuggestion;
                    res.totalSumEditField = app.TotalSumEditFieldSuggestion;
                    res.accountSumEditField = app.AccountSumEditFieldSuggestion;
                    res.investSumEditField = app.InvestSumEditFieldSuggestion;
                    res.returnSumEditField = app.ReturnSumEditFieldSuggestion;
                    res.totalIncomeEditField = app.TotalIncomeEditFieldSuggestion;
                    res.initialSumEditField = app.InitialSumEditFieldSuggestion;
                    res.fromtable = app.SuggestionTable;
                    res.sum = app.sum.suggestion;
                    res.globalPlabel = app.GlobalPvalue_4;
                    res.vsconPlabel = app.vsControlPValue_4;
                case {'Deprecation','建议不买入'}
                    res.table = app.SumTableDeprecation;
                    res.increaseLabel = app.IncreaseLabelDeprecation;
                    res.increaseRateLabel = app.IncreaseRateLabelDeprecation;
                    res.totalSumEditField = app.TotalSumEditFieldDeprecation;
                    res.accountSumEditField = app.AccountSumEditFieldDeprecation;
                    res.investSumEditField = app.InvestSumEditFieldDeprecation;
                    res.returnSumEditField = app.ReturnSumEditFieldDeprecation;
                    res.totalIncomeEditField = app.TotalIncomeEditFieldDeprecation;
                    res.initialSumEditField = app.InitialSumEditFieldDeprecation;
                    res.fromtable = app.DeprecationTable;
                    res.sum = app.sum.deprecation;
                    res.globalPlabel = app.GlobalPvalue_3;
                    res.vsconPlabel = app.vsControlPValue_3;
                case {'Neutral','中性'}
                    res.table = app.SumTableNeutral;
                    res.increaseLabel = app.IncreaseLabelNeutral;
                    res.increaseRateLabel = app.IncreaseRateLabelNeutral;
                    res.totalSumEditField = app.TotalSumEditFieldNeutral;
                    res.accountSumEditField = app.AccountSumEditFieldNeutral;
                    res.investSumEditField = app.InvestSumEditFieldNeutral;
                    res.returnSumEditField = app.ReturnSumEditFieldNeutral;
                    res.totalIncomeEditField = app.TotalIncomeEditFieldNeutral;
                    res.initialSumEditField = app.InitialSumEditFieldNeutral;
                    res.fromtable = app.NeutralTable;
                    res.sum = app.sum.neutral;
                    res.globalPlabel = app.GlobalPvalue_2;
                    res.vsconPlabel = app.vsControlPValue_2;
                case {'Control (Random)','随机对照'}
                    res.table = app.SumTableControlRandom;
                    res.increaseLabel = app.IncreaseLabelControlRandom;
                    res.increaseRateLabel = app.IncreaseRateLabelControlRandom;
                    res.totalSumEditField = app.TotalSumEditFieldControlRandom;
                    res.accountSumEditField = app.AccountSumEditFieldControlRandom;
                    res.investSumEditField = app.InvestSumEditFieldControlRandom;
                    res.returnSumEditField = app.ReturnSumEditFieldControlRandom;
                    res.totalIncomeEditField = app.TotalIncomeEditFieldControlRandom;
                    res.initialSumEditField = app.InitialSumEditFieldControlRandom;
                    res.fromtable = app.ControlTable;
                    res.sum = app.sum.control;
                    res.globalPlabel = app.GlobalPvalue;
                    res.vsconPlabel = app.vsControlPValue;
                otherwise
                    res = [];
            end
        end
        
        function setTabHandle(app,tabname,sumhandle)
            switch tabname
                case {'Suggestion','建议买入'}
                    app.sum.suggestion = sumhandle;  
                case {'Deprecation','建议不买入'}
                    app.sum.deprecation = sumhandle;
                case {'Neutral','中性'}
                    app.sum.neutral = sumhandle;
                case {'Control (Random)','随机对照'}
                    app.sum.control = sumhandle;
            end
        end
        
        function res = getRecommandTabHandle(app)
            stab = app.RecommandTableGroup.SelectedTab.Title;
            res.tabname = stab;
            switch stab
                case {'Suggestion','建议买入'}
                    res.table = app.SuggestionTable;                   
                case {'Deprecation','建议不买入'}
                    res.table = app.DeprecationTable;                    
                case {'Neutral','中性'}
                    res.table = app.NeutralTable; 
                case {'Control (Random)','随机对照'}
                    res.table = app.ControlTable;
            end
        end
        
        function changeLang(app,lang)
            langpack = app.hand.lang.lang;
        
            switch lang
                case 'English'
                   setlang(1);
                   app.hand.langid = 1;
                case 'Chinese'
                   setlang(2);
                   app.hand.langid = 2;
                case 'Japanese'
                    setlang(3);
                   app.hand.langid = 3;
                case 'Russian'
                    setlang(4);
                   app.hand.langid = 4;
            end
            
            function setlang(langid)
                % Menus
                app.FileMenu.Text =                                         langpack{1,langid};
                    app.SaveMenu.Text =                                     langpack{2,langid};
                        app.SaveRecommendationDataDefaultMenu.Text =        langpack{3,langid};
                        app.SaveRecommendationDataSpecifiedMenu.Text =      langpack{4,langid};
                        app.SaveTradingDataDefaultMenu.Text =               langpack{5,langid};
                        app.SaveTradingDataSpecifiedMenu.Text =             langpack{6,langid};
                    app.LoadMenu.Text =                                     langpack{7,langid};
                        app.LoadRecommendationDataDefaultMenu.Text =        langpack{8,langid};
                        app.LoadRecommendationDataSpecifiedMenu.Text =      langpack{9,langid};
                        app.LoadTradingDataDefaultMenu.Text =               langpack{10,langid};
                        app.LoadTradingDataSpecifiedMenu.Text =             langpack{11,langid};
                app.OptionsMenu.Text =                                      langpack{12,langid};
                        app.T1TradingRealMenu.Text =                        langpack{13,langid};
                        app.PriceBacthUpdateMenu.Text =                     langpack{14,langid};
                        app.RollBackOneDayDebugModeMenu.Text =              langpack{100,langid};
                        app.DiscardRecommendationListMenu.Text =            langpack{101,langid};
                        app.AutoSaveRecommendationListMenu.Text =           langpack{104,langid};
                        app.LastAutoSaveMenu.Text =                         langpack{105,langid};
                        app.LanguagesMenu.Text =                            langpack{15,langid};
                            app.EnglishMenu.Text =                          langpack{16,langid};
                            app.ChineseMenu.Text =                          langpack{17,langid};
                app.ScriptMenu.Text =                                       langpack{18,langid};
                app.HelpMenu.Text =                                         langpack{19,langid};
                    app.AboutMenu.Text =                                    langpack{20,langid};
                    app.DevelopersMenu.Text =                               langpack{21,langid};
                % Tabs
                app.RealTimeMonitorTab.Title =                              langpack{22,langid};
                app.StockListTab.Title =                                    langpack{23,langid};
                app.RecommendationSystemTab.Title =                         langpack{24,langid};
                    app.SuggestionTab.Title =                               langpack{25,langid};
                    app.DeprecationTab.Title =                              langpack{26,langid};
                    app.NeutralTab.Title =                                  langpack{27,langid};
                    app.ControlRandomTab.Title =                            langpack{28,langid};
                app.TradeSimulationSystemTab.Title =                        langpack{29,langid};
                    app.PurchaseInTab.Title =                                       langpack{30,langid};
                    app.SellOutTab.Title =                                      langpack{31,langid};
                    app.SuggestionTabSum.Title =                            langpack{32,langid};
                    app.DeprecationTabSum.Title =                           langpack{33,langid};
                    app.NeutralTabSum.Title =                               langpack{34,langid};
                    app.ControlRandomTabSum.Title =                         langpack{35,langid};
                    app.StatisticalAnalysisTab.Title =                      langpack{91,langid};
                % Buttons
                app.RealTimeButton.Text =                                   langpack{36,langid};
                app.PredictionButton.Text =                                 langpack{37,langid}; 
                app.SearchButton.Text =                                     langpack{38,langid}; 
                app.StartButton.Text =                                      langpack{39,langid}; 
                app.InPoolButton.Text =                                     langpack{40,langid}; 
                app.ALLButton.Text =                                        langpack{41,langid}; 
                app.ClearKMSButton.Text =                                   langpack{42,langid};
                app.ClearCSButton.Text =                                    langpack{43,langid};
                app.RunButton.Text =                                        langpack{44,langid}; 
                app.ResetButtonRecommender.Text =                           langpack{45,langid}; 
                app.SaveDataButton.Text =                                   langpack{46,langid}; 
                app.LoadDataButton.Text =                                   langpack{47,langid}; 
                app.AddFromRSButton.Text =                                  langpack{48,langid}; 
                app.ClearButton.Text =                                      langpack{49,langid};
                app.PurchaseButton.Text =                                   langpack{50,langid};
                app.AddCandidatesButton.Text =                              langpack{51,langid}; 
                app.SellButton.Text =                                       langpack{52,langid}; 
                app.AutoSellButton.Text =                                   langpack{53,langid}; 
                app.ResetButtonMonitor.Text =                               langpack{54,langid}; 
                app.UnLockButton.Text =                                     langpack{55,langid};
                app.ShuffleButton.Text =                                    langpack{88,langid};
                
                % Labels
                app.DesginedandProgrammedbyZhangHengLabel.Text =    langpack{56,langid}; 
                app.TradeMonitorSwitchLabel.Text=                           langpack{57,langid}; 
                app.RePredCheckBox.Text =                                   langpack{58,langid}; 
                app.CSLabel.Text =                                          langpack{59,langid}; 
                app.KMSLabel.Text =                                         langpack{60,langid}; 
                app.StockPoolLabel.Text =                                   langpack{61,langid}; 
                app.MaxLabel.Text =                                         langpack{62,langid};
                app.ThresholdEditFieldLabel.Text =                          langpack{63,langid};
                
                app.Label99.Text =                                          langpack{64,langid};
                app.TotalSumEditFieldLabel.Text =                           langpack{65,langid};
                app.InvestSumEditFieldLabel.Text =                          langpack{66,langid};
                app.ReturnSumEditFieldLabel.Text =                          langpack{67,langid};
                app.TotalIncomeEditFieldLabel.Text =                        langpack{68,langid};
                app.InitialSumEditFieldLabel.Text =                         langpack{69,langid};
                app.AccountSumEditFieldLabel.Text =                         langpack{70,langid};
                
                app.Label99_2.Text =                                        langpack{64,langid};
                app.TotalSumEditField_2Label.Text =                         langpack{65,langid};
                app.InvestSumEditField_2Label.Text =                        langpack{66,langid};
                app.ReturnSumEditField_2Label.Text =                        langpack{67,langid};
                app.TotalIncomeEditField_2Label.Text =                      langpack{68,langid};
                app.InitialSumEditField_2Label.Text =                       langpack{69,langid};
                app.AccountSumEditFieldLabel_2.Text =                       langpack{70,langid};
                
                app.Label99_4.Text =                                        langpack{64,langid};
                app.TotalSumEditField_4Label.Text =                         langpack{65,langid};
                app.InvestSumEditField_4Label.Text =                        langpack{66,langid};
                app.ReturnSumEditField_4Label.Text =                        langpack{67,langid};
                app.TotalIncomeEditField_4Label.Text =                      langpack{68,langid};
                app.InitialSumEditField_4Label.Text =                       langpack{69,langid};
                app.AccountSumEditFieldLabel_4.Text =                       langpack{70,langid};
                
                app.Label99_3.Text =                                        langpack{64,langid};
                app.TotalSumEditField_3Label.Text =                         langpack{65,langid};
                app.InvestSumEditField_3Label.Text =                        langpack{66,langid};
                app.ReturnSumEditField_3Label.Text =                        langpack{67,langid};
                app.TotalIncomeEditField_3Label.Text =                      langpack{68,langid};
                app.InitialSumEditField_3Label.Text =                       langpack{69,langid};
                app.AccountSumEditFieldLabel_3.Text =                       langpack{70,langid};
                app.GloballyLabel_6.Text =                                  langpack{89,langid};
                app.GloballyLabel_5.Text =                                  langpack{89,langid};
                app.GloballyLabel_2.Text =                                  langpack{89,langid};
                app.GloballyLabel.Text =                                    langpack{89,langid};
                app.vsControlLabel_6.Text =                                 langpack{90,langid};
                app.vsControlLabel_5.Text =                                 langpack{90,langid};
                app.vsControlLabel_2.Text =                                 langpack{90,langid};
                app.vsControlLabel.Text =                                   langpack{90,langid};
                
                % Table
                app.SuggestionTable.ColumnName{1} =                         langpack{71,langid};
                app.SuggestionTable.ColumnName{2} =                         langpack{72,langid};
                app.SuggestionTable.ColumnName{3} =                         langpack{73,langid};
                app.SuggestionTable.ColumnName{4} =                         langpack{74,langid};
                app.SuggestionTable.ColumnName{5} =                         langpack{75,langid}; % Price
                app.SuggestionTable.ColumnName{6} =                         langpack{83,langid};
                app.SuggestionTable.ColumnName{7} =                         langpack{84,langid};
                app.SuggestionTable.ColumnName{8} =                         langpack{77,langid};
                app.SuggestionTable.ColumnName{9} =                         langpack{78,langid};
                
                app.DeprecationTable.ColumnName{1} =                        langpack{71,langid};
                app.DeprecationTable.ColumnName{2} =                        langpack{72,langid};
                app.DeprecationTable.ColumnName{3} =                        langpack{73,langid};
                app.DeprecationTable.ColumnName{4} =                        langpack{74,langid};
                app.DeprecationTable.ColumnName{5} =                        langpack{75,langid};
                app.DeprecationTable.ColumnName{6} =                        langpack{83,langid};
                app.DeprecationTable.ColumnName{7} =                        langpack{84,langid};
                app.DeprecationTable.ColumnName{8} =                        langpack{77,langid};
                app.DeprecationTable.ColumnName{9} =                        langpack{78,langid};
                
                app.NeutralTable.ColumnName{1} =                            langpack{71,langid};
                app.NeutralTable.ColumnName{2} =                            langpack{72,langid};
                app.NeutralTable.ColumnName{3} =                            langpack{73,langid};
                app.NeutralTable.ColumnName{4} =                            langpack{74,langid};
                app.NeutralTable.ColumnName{5} =                            langpack{75,langid};
                app.NeutralTable.ColumnName{6} =                            langpack{83,langid};
                app.NeutralTable.ColumnName{7} =                            langpack{84,langid};
                app.NeutralTable.ColumnName{8} =                            langpack{77,langid};
                app.NeutralTable.ColumnName{9} =                            langpack{78,langid};
                
                app.ControlTable.ColumnName{1} =                            langpack{71,langid};
                app.ControlTable.ColumnName{2} =                            langpack{72,langid};
                app.ControlTable.ColumnName{3} =                            langpack{73,langid};
                app.ControlTable.ColumnName{4} =                            langpack{74,langid};
                app.ControlTable.ColumnName{5} =                            langpack{75,langid};
                app.ControlTable.ColumnName{6} =                            langpack{83,langid};
                app.ControlTable.ColumnName{7} =                            langpack{84,langid};
                app.ControlTable.ColumnName{8} =                            langpack{77,langid};
                app.ControlTable.ColumnName{9} =                            langpack{78,langid};


                app.SellTable.ColumnName{1} =                               langpack{79,langid};
                app.SellTable.ColumnName{2} =                               langpack{72,langid};
                app.SellTable.ColumnName{3} =                               langpack{73,langid};
                app.SellTable.ColumnName{4} =                               langpack{74,langid};
                app.SellTable.ColumnName{5} =                               langpack{75,langid}; % Price
                app.SellTable.ColumnName{6} =                               langpack{80,langid};
                app.SellTable.ColumnName{7} =                               langpack{77,langid};
                app.SellTable.ColumnName{8} =                               langpack{81,langid};
                app.SellTable.ColumnName{9} =                               langpack{82,langid};
                app.SellTable.ColumnName{10} =                              langpack{71,langid};
                
                app.BuyTable.ColumnName{1} =                                langpack{72,langid};
                app.BuyTable.ColumnName{2} =                                langpack{73,langid};
                app.BuyTable.ColumnName{3} =                                langpack{74,langid};
                app.BuyTable.ColumnName{4} =                                langpack{75,langid}; % Price
                app.BuyTable.ColumnName{5} =                                langpack{83,langid};
                app.BuyTable.ColumnName{6} =                                langpack{84,langid};
                app.BuyTable.ColumnName{7} =                                langpack{77,langid};
                app.BuyTable.ColumnName{8} =                                langpack{78,langid};
                app.BuyTable.ColumnName{9} =                                langpack{81,langid};
                
                app.SumTableSuggestion.ColumnName{1} =                      langpack{79,langid};
                app.SumTableSuggestion.ColumnName{2} =                      langpack{72,langid};
                app.SumTableSuggestion.ColumnName{3} =                      langpack{73,langid};
                app.SumTableSuggestion.ColumnName{4} =                      langpack{74,langid};
                app.SumTableSuggestion.ColumnName{5} =                      langpack{75,langid}; %
                app.SumTableSuggestion.ColumnName{6} =                      langpack{102,langid};
                app.SumTableSuggestion.ColumnName{7} =                      langpack{103,langid};
                app.SumTableSuggestion.ColumnName{8} =                      langpack{80,langid};
                app.SumTableSuggestion.ColumnName{9} =                      langpack{86,langid};
                app.SumTableSuggestion.ColumnName{10} =                     langpack{83,langid};
                app.SumTableSuggestion.ColumnName{11} =                     langpack{84,langid};
                app.SumTableSuggestion.ColumnName{12} =                     langpack{78,langid};
                app.SumTableSuggestion.ColumnName{13} =                     langpack{81,langid};
                app.SumTableSuggestion.ColumnName{14} =                     langpack{87,langid};
                
                app.SumTableDeprecation.ColumnName{1} =                     langpack{79,langid};
                app.SumTableDeprecation.ColumnName{2} =                     langpack{72,langid};
                app.SumTableDeprecation.ColumnName{3} =                     langpack{73,langid};
                app.SumTableDeprecation.ColumnName{4} =                     langpack{74,langid};
                app.SumTableDeprecation.ColumnName{5} =                     langpack{75,langid};
                app.SumTableDeprecation.ColumnName{6} =                     langpack{102,langid};
                app.SumTableDeprecation.ColumnName{7} =                     langpack{103,langid};
                app.SumTableDeprecation.ColumnName{8} =                     langpack{80,langid};
                app.SumTableDeprecation.ColumnName{9} =                     langpack{86,langid};
                app.SumTableDeprecation.ColumnName{10} =                    langpack{83,langid};
                app.SumTableDeprecation.ColumnName{11} =                    langpack{84,langid};
                app.SumTableDeprecation.ColumnName{12} =                    langpack{78,langid};
                app.SumTableDeprecation.ColumnName{13} =                    langpack{81,langid};
                app.SumTableDeprecation.ColumnName{14} =                    langpack{87,langid};
                
                app.SumTableNeutral.ColumnName{1} =                         langpack{79,langid};
                app.SumTableNeutral.ColumnName{2} =                         langpack{72,langid};
                app.SumTableNeutral.ColumnName{3} =                         langpack{73,langid};
                app.SumTableNeutral.ColumnName{4} =                         langpack{74,langid};
                app.SumTableNeutral.ColumnName{5} =                         langpack{75,langid};
                app.SumTableNeutral.ColumnName{6} =                         langpack{102,langid};
                app.SumTableNeutral.ColumnName{7} =                         langpack{103,langid};
                app.SumTableNeutral.ColumnName{8} =                         langpack{80,langid};
                app.SumTableNeutral.ColumnName{9} =                         langpack{86,langid};
                app.SumTableNeutral.ColumnName{10} =                        langpack{83,langid};
                app.SumTableNeutral.ColumnName{11} =                        langpack{84,langid};
                app.SumTableNeutral.ColumnName{12} =                        langpack{78,langid};
                app.SumTableNeutral.ColumnName{13} =                        langpack{81,langid};
                app.SumTableNeutral.ColumnName{14} =                        langpack{87,langid};
                
                app.SumTableControlRandom.ColumnName{1} =                   langpack{79,langid};
                app.SumTableControlRandom.ColumnName{2} =                   langpack{72,langid};
                app.SumTableControlRandom.ColumnName{3} =                   langpack{73,langid};
                app.SumTableControlRandom.ColumnName{4} =                   langpack{74,langid};
                app.SumTableControlRandom.ColumnName{5} =                   langpack{75,langid};
                app.SumTableControlRandom.ColumnName{6} =                   langpack{102,langid};
                app.SumTableControlRandom.ColumnName{7} =                   langpack{103,langid};
                app.SumTableControlRandom.ColumnName{8} =                   langpack{80,langid};
                app.SumTableControlRandom.ColumnName{9} =                   langpack{86,langid};
                app.SumTableControlRandom.ColumnName{10} =                  langpack{83,langid};
                app.SumTableControlRandom.ColumnName{11} =                  langpack{84,langid};
                app.SumTableControlRandom.ColumnName{12} =                  langpack{78,langid};
                app.SumTableControlRandom.ColumnName{13} =                  langpack{81,langid};
                app.SumTableControlRandom.ColumnName{14} =                  langpack{87,langid};
                
            end
        end
        
        function calPvalue(app)
            calp(app.sum.suggestion,'Suggestion');
            calp(app.sum.deprecation,'Deprecation');
            calp(app.sum.neutral,'Neutral');
            calp(app.sum.control,'Control (Random)');
            
            function calp(sumhand,tabname)
                if ~(isfield(sumhand,'numincrease') && isfield(sumhand,'numall'))
                    return;
                end
                if ~(isfield(app.sum.control,'numincrease') && isfield(app.sum.control,'numall'))
                    return;
                end
                thand = app.getTabHandle(tabname);
                [vsConPval,~] = chi2test([sumhand.numincrease,(sumhand.numall-sumhand.numincrease);app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease)]);
                if vsConPval <= 0.001
                    set(thand.vsconPlabel,'text',['p = ' num2str(round(vsConPval,4)) ' (***)']);
                elseif vsConPval <= 0.01
                    set(thand.vsconPlabel,'text',['p = ' num2str(round(vsConPval,4)) ' (**)']);
                elseif vsConPval <= 0.05
                    set(thand.vsconPlabel,'text',['p = ' num2str(round(vsConPval,4)) ' (*)']);
                else 
                    set(thand.vsconPlabel,'text',['p = ' num2str(round(vsConPval,4))]);
                end
                if ~(isfield(app.sum.suggestion,'numincrease') && isfield(app.sum.suggestion,'numall'))
                    return;
                end
                if ~(isfield(app.sum.deprecation,'numincrease') && isfield(app.sum.deprecation,'numall'))
                    return;
                end
                if ~(isfield(app.sum.neutral,'numincrease') && isfield(app.sum.neutral,'numall'))
                    return;
                end
                [globalPval,~] = chi2test([
                    app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);
                    app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease);
                    app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease);
                    app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease)
                ]);
                if globalPval <= 0.001
                    set(thand.globalPlabel,'text',['p = ' num2str(round(globalPval,4)) '(***)']);
                elseif globalPval <= 0.01
                    set(thand.globalPlabel,'text',['p = ' num2str(round(globalPval,4)) '(**)']);
                elseif globalPval <= 0.05
                    set(thand.globalPlabel,'text',['p = ' num2str(round(globalPval,4)) '(*)']);
                else 
                    set(thand.globalPlabel,'text',['p = ' num2str(round(globalPval,4))]);
                end
            end
        end
        
        function plotFigs(app)
            if ~(isfield(app.sum.suggestion,'numincrease') && isfield(app.sum.suggestion,'numall') && isfield(app.sum.suggestion,'tabdata'))
                return;
            end
            if ~(isfield(app.sum.deprecation,'numincrease') && isfield(app.sum.deprecation,'numall') && isfield(app.sum.deprecation,'tabdata'))
                return;
            end
            if ~(isfield(app.sum.neutral,'numincrease') && isfield(app.sum.neutral,'numall') && isfield(app.sum.neutral,'tabdata'))
                return;
            end
            if ~(isfield(app.sum.control,'numincrease') && isfield(app.sum.control,'numall') && isfield(app.sum.control,'tabdata'))
                return;
            end
            ampsug = (cell2mat(app.sum.suggestion.tabdata(:,5)) - cell2mat(app.sum.suggestion.tabdata(:,8))) ./ cell2mat(app.sum.suggestion.tabdata(:,8));
            ampdep = (cell2mat(app.sum.deprecation.tabdata(:,5)) - cell2mat(app.sum.deprecation.tabdata(:,8))) ./ cell2mat(app.sum.deprecation.tabdata(:,8));
            ampneu = (cell2mat(app.sum.neutral.tabdata(:,5)) - cell2mat(app.sum.neutral.tabdata(:,8))) ./ cell2mat(app.sum.neutral.tabdata(:,8));
            ampcon = (cell2mat(app.sum.control.tabdata(:,5)) - cell2mat(app.sum.control.tabdata(:,8))) ./ cell2mat(app.sum.control.tabdata(:,8));
            cla(app.AmpAxes);
            cla(app.RatioAxes);
            mean_ampsug = mean(ampsug);
            mean_ampdep = mean(ampdep);
            mean_ampneu = mean(ampneu);
            mean_ampcon = mean(ampcon);
            std_ampsug = std(ampsug);
            std_ampdep = std(ampdep);
            std_ampneu = std(ampneu);
            std_ampcon = std(ampcon);
            
            hold(app.AmpAxes,'on');
            scatter(app.AmpAxes,1.5-rand(numel(ampsug),1),ampsug);
            scatter(app.AmpAxes,3.5-rand(numel(ampdep),1),ampdep);
            scatter(app.AmpAxes,5.5-rand(numel(ampneu),1),ampneu);
            scatter(app.AmpAxes,7.5-rand(numel(ampcon),1),ampcon);
            
            
            errorbar(app.AmpAxes,1,mean_ampsug,std_ampsug,'-s','Color',[0 0 0],'MarkerFaceColor',[0 0 0],'LineWidth',1.5);
            errorbar(app.AmpAxes,3,mean_ampdep,std_ampdep,'-s','Color',[0 0 0],'MarkerFaceColor',[0 0 0],'LineWidth',1.5);
            errorbar(app.AmpAxes,5,mean_ampneu,std_ampneu,'-s','Color',[0 0 0],'MarkerFaceColor',[0 0 0],'LineWidth',1.5);
            errorbar(app.AmpAxes,7,mean_ampcon,std_ampcon,'-s','Color',[0 0 0],'MarkerFaceColor',[0 0 0],'LineWidth',1.5);
            
            text(app.AmpAxes,0.5,mean_ampsug+std_ampsug+0.05,num2str(mean_ampsug));
            text(app.AmpAxes,2.5,mean_ampdep+std_ampdep+0.05,num2str(mean_ampdep));
            text(app.AmpAxes,4.5,mean_ampneu+std_ampneu+0.05,num2str(mean_ampneu));
            text(app.AmpAxes,6.5,mean_ampcon+std_ampcon+0.05,num2str(mean_ampcon));
            
            
            set(app.AmpAxes,'xtick',[1 3 5 7],'xticklabel',{app.hand.lang.lang{32,app.hand.langid},app.hand.lang.lang{33,app.hand.langid},app.hand.lang.lang{34,app.hand.langid},app.hand.lang.lang{98,app.hand.langid}});
%             set(app.AmpAxes,'xticklabelrotation',15);
            
            amp_globalPvalue = pz_oneway_anova(ampcon,ampsug,ampdep,ampneu);
            amp_sugVSconPvalue = pz_ttest(ampsug,ampcon);
            amp_depVSconPvalue = pz_ttest(ampdep,ampcon);
            amp_neuVSconPvalue = pz_ttest(ampneu,ampcon);
            amp_sugVSdepPvalue = pz_ttest(ampsug,ampdep);
            amp_sugVSneuPvalue = pz_ttest(ampsug,ampneu);
            amp_depVSneuPvalue = pz_ttest(ampdep,ampneu);


            amp_sta = [app.hand.lang.lang{93,app.hand.langid} newline... 
                '--- ' app.hand.lang.lang{96,app.hand.langid} ' ---' newline...
                app.hand.lang.lang{89,app.hand.langid} '    p = ' num2str(amp_globalPvalue) '    ' numstar(amp_globalPvalue) newline...
                '--- ' app.hand.lang.lang{95,app.hand.langid} ' ---' newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(amp_sugVSconPvalue) '  ' numstar(amp_sugVSconPvalue) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(amp_depVSconPvalue) '  ' numstar(amp_depVSconPvalue) newline...
                '< ' app.hand.lang.lang{34,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(amp_neuVSconPvalue) '  ' numstar(amp_neuVSconPvalue) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{33,app.hand.langid} ' >' newline '    p = ' num2str(amp_sugVSdepPvalue) '  ' numstar(amp_sugVSdepPvalue) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(amp_sugVSneuPvalue) '  ' numstar(amp_sugVSneuPvalue) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(amp_depVSneuPvalue) '  ' numstar(amp_depVSneuPvalue)
                ];
            
            app.AmpStaArea.Value = amp_sta;
                
            
            ratio_data = [...
                app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);...
                app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease);...
                app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease);...
                app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease)...
            ];
            
            bata = barh(app.RatioAxes,ratio_data,'stacked');
            set(bata(1),'facecolor',[1 0 0]);
            set(bata(2),'facecolor',[0 1 0]);
            set(app.RatioAxes,'ytick',[1 2 3 4],'yticklabel',{app.hand.lang.lang{98,app.hand.langid},app.hand.lang.lang{34,app.hand.langid},app.hand.lang.lang{33,app.hand.langid},app.hand.lang.lang{32,app.hand.langid}});
            set(app.RatioAxes,'yticklabelrotation',70);
%             legend(app.RatioAxes,{'Increase','Decline'});          
            
            text(app.RatioAxes,app.sum.suggestion.numincrease,4,[num2str(round(app.sum.suggestion.numincrease*100/app.sum.suggestion.numall,1)) '%']);
            text(app.RatioAxes,app.sum.deprecation.numincrease,3,[num2str(round(app.sum.deprecation.numincrease*100/app.sum.deprecation.numall,1)) '%']);
            text(app.RatioAxes,app.sum.neutral.numincrease,2,[num2str(round(app.sum.neutral.numincrease*100/app.sum.neutral.numall,1)) '%']);
            text(app.RatioAxes,app.sum.control.numincrease,1,[num2str(round(app.sum.control.numincrease*100/app.sum.control.numall,1)) '%']);
            
            [ratio_globalPval,~] = chi2test([
                    app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);
                    app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease);
                    app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease);
                    app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease)
                ]);
            ratio_sugVSconPvalue = chi2test([
                    app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);
                    app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease)
            ]);
            ratio_depVSconPvalue = chi2test([
                    app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);
                    app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease)
            ]);
            ratio_neuVSconPvalue = chi2test([
                    app.sum.control.numincrease,(app.sum.control.numall-app.sum.control.numincrease);
                    app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease)
            ]);
            ratio_sugVSdepPvalue = chi2test([
                    app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease);
                    app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease)
            ]);
            ratio_sugVSneuPvalue = chi2test([
                    app.sum.suggestion.numincrease,(app.sum.suggestion.numall-app.sum.suggestion.numincrease);
                    app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease)
            ]);
            ratio_depVSneuPvalue = chi2test([
                    app.sum.deprecation.numincrease,(app.sum.deprecation.numall-app.sum.deprecation.numincrease);
                    app.sum.neutral.numincrease,(app.sum.neutral.numall-app.sum.neutral.numincrease)
            ]);
        
            ratio_sta = [app.hand.lang.lang{99,app.hand.langid} newline... 
                '--- ' app.hand.lang.lang{97,app.hand.langid} ' ---' newline...
                app.hand.lang.lang{89,app.hand.langid} '    p = ' num2str(ratio_globalPval) '    ' numstar(ratio_globalPval) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(ratio_sugVSconPvalue) '  ' numstar(ratio_sugVSconPvalue) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(ratio_depVSconPvalue) '  ' numstar(ratio_depVSconPvalue) newline...
                '< ' app.hand.lang.lang{34,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(ratio_neuVSconPvalue) '  ' numstar(ratio_neuVSconPvalue) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{33,app.hand.langid} ' >' newline '    p = ' num2str(ratio_sugVSdepPvalue) '  ' numstar(ratio_sugVSdepPvalue) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(ratio_sugVSneuPvalue) '  ' numstar(ratio_sugVSneuPvalue) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(ratio_depVSneuPvalue) '  ' numstar(ratio_depVSneuPvalue)
                ];
            
            app.ProStaArea.Value = ratio_sta;
            
            
            % 命中率&赢率
            sug.low = cell2mat(app.sum.suggestion.tabdata(:,6));
            sug.high = cell2mat(app.sum.suggestion.tabdata(:,7));
            sug.predlow = cell2mat(app.sum.suggestion.tabdata(:,10));
            sug.predhigh = cell2mat(app.sum.suggestion.tabdata(:,11));
            sug.buyprice = cell2mat(app.sum.suggestion.tabdata(:,8));
            
            dep.low = cell2mat(app.sum.deprecation.tabdata(:,6));
            dep.high = cell2mat(app.sum.deprecation.tabdata(:,7));
            dep.predlow = cell2mat(app.sum.deprecation.tabdata(:,10));
            dep.predhigh = cell2mat(app.sum.deprecation.tabdata(:,11));
            dep.buyprice = cell2mat(app.sum.deprecation.tabdata(:,8));
            
            neu.low = cell2mat(app.sum.neutral.tabdata(:,6));
            neu.high = cell2mat(app.sum.neutral.tabdata(:,7));
            neu.predlow = cell2mat(app.sum.neutral.tabdata(:,10));
            neu.predhigh = cell2mat(app.sum.neutral.tabdata(:,11));
            neu.buyprice = cell2mat(app.sum.neutral.tabdata(:,8));
            
            con.low = cell2mat(app.sum.control.tabdata(:,6));
            con.high = cell2mat(app.sum.control.tabdata(:,7));
            con.predlow = cell2mat(app.sum.control.tabdata(:,10));
            con.predhigh = cell2mat(app.sum.control.tabdata(:,11));
            con.buyprice = cell2mat(app.sum.control.tabdata(:,8));
            
            % 命中率
            [sug_hit suga sugb] = hitness(sug);
            [dep_hit depa depb] = hitness(dep);
            [neu_hit neua neub] = hitness(neu);
            [con_hit cona conb] = hitness(con);
            
            phit = chi([suga sugb;depa depb;neua neub;cona conb]);
            
            % 实际赢率
            [sug_fe suga sugb] = factearn(sug);
            [dep_fe depa depb] = factearn(dep);
            [neu_fe neua neub] = factearn(neu);
            [con_fe cona conb] = factearn(con);
            
            pfe = chi([suga sugb;depa depb;neua neub;cona conb]);
            
            % 实际稳赢率
            [sug_sw suga sugb] = surelywin(sug);
            [dep_sw depa depb] = surelywin(dep);
            [neu_sw neua neub] = surelywin(neu);
            [con_sw cona conb] = surelywin(con);
            
            psw = chi([suga sugb;depa depb;neua neub;cona conb]);
            
            % 预测赢率
            [sug_pe suga sugb] = predearn(sug);
            [dep_pe depa depb] = predearn(dep);
            [neu_pe neua neub] = predearn(neu);
            [con_pe cona conb] = predearn(con);
            
            ppe = chi([suga sugb;depa depb;neua neub;cona conb]);


            hitdata = [sug_hit dep_hit neu_hit con_hit];
            edata = [...
                sug_pe sug_fe sug_sw;...
                dep_pe dep_fe dep_sw;...
                neu_pe neu_fe neu_sw;...
                con_pe con_fe con_sw...
                ];
            
            bar(app.HitStaAxes,[1 2 3 4],hitdata);
            set(app.HitStaAxes,'xtick',[1 2 3 4],'xticklabel',{app.hand.lang.lang{32,app.hand.langid},app.hand.lang.lang{33,app.hand.langid},app.hand.lang.lang{34,app.hand.langid},app.hand.lang.lang{98,app.hand.langid}});
            
            bar(app.EarnStaAxes,edata);
            set(app.EarnStaAxes,'xtick',[1 2 3 4],'xticklabel',{app.hand.lang.lang{32,app.hand.langid},app.hand.lang.lang{33,app.hand.langid},app.hand.lang.lang{34,app.hand.langid},app.hand.lang.lang{98,app.hand.langid}});
            
            hit_sta = [app.hand.lang.lang{99,app.hand.langid} newline... 
                '--- ' app.hand.lang.lang{97,app.hand.langid} ' ---' newline...
                app.hand.lang.lang{89,app.hand.langid} '    p = ' num2str(phit.g) '    ' numstar(phit.g) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(phit.svc) '  ' numstar(phit.svc) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(phit.dvc) '  ' numstar(phit.dvc) newline...
                '< ' app.hand.lang.lang{34,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(phit.nvc) '  ' numstar(phit.nvc) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{33,app.hand.langid} ' >' newline '    p = ' num2str(phit.svd) '  ' numstar(phit.svd) newline...
                '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(phit.svn) '  ' numstar(phit.svn) newline...
                '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(phit.dvn) '  ' numstar(phit.dvn)
                ];
            
            app.HitStaArea.Value = hit_sta;
                
            fe_sta = [app.hand.lang.lang{99,app.hand.langid} newline... 
            '--- ' app.hand.lang.lang{97,app.hand.langid} ' ---' newline...
            app.hand.lang.lang{89,app.hand.langid} '    p = ' num2str(pfe.g) '    ' numstar(pfe.g) newline...
            '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(pfe.svc) '  ' numstar(pfe.svc) newline...
            '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(pfe.dvc) '  ' numstar(pfe.dvc) newline...
            '< ' app.hand.lang.lang{34,app.hand.langid} ' vs ' app.hand.lang.lang{35,app.hand.langid} ' >' newline '    p = ' num2str(pfe.nvc) '  ' numstar(pfe.nvc) newline...
            '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{33,app.hand.langid} ' >' newline '    p = ' num2str(pfe.svd) '  ' numstar(pfe.svd) newline...
            '< ' app.hand.lang.lang{32,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(pfe.svn) '  ' numstar(pfe.svn) newline...
            '< ' app.hand.lang.lang{33,app.hand.langid} ' vs ' app.hand.lang.lang{34,app.hand.langid} ' >' newline '    p = ' num2str(pfe.dvn) '  ' numstar(pfe.dvn)
            ];
            
            app.EarnStaArea.Value = fe_sta;
            
            % chi
            function res = chi(data)
                res.g = chi2test(data);
                res.svc = chi2test(data([1 4],:));
                res.dvc = chi2test(data([2 4],:));
                res.nvc = chi2test(data([3 4],:));
                res.svd = chi2test(data([1 2],:));
                res.svn = chi2test(data([1 3],:));
                res.dvn = chi2test(data([2 3],:));
            end
            
            % 命中率
            function [res a b] = hitness(data)
                numhit = 0;
                for i = 1 : numel(data.low)
                    if ~(data.low(i) > data.predhigh(i) || data.high(i) < data.predlow(i))
                        numhit = numhit + 1;
                    end
                end
                res = numhit / numel(data.low);
                a = numhit;
                b = numel(data.low) - numhit;
            end
            % 实际赢率
            function [res a b] = factearn(data)
                numearn = 0;
                for i = 1 : numel(data.high)
                    if data.high(i) > data.buyprice(i)
                        numearn = numearn + 1;
                    end
                end
                res = numearn / numel(data.high);
                a = numearn;
                b = numel(data.high) - numearn;
            end
            % 预测赢率
            function [res a b] = predearn(data)
                numearn = 0;
                for i = 1 : numel(data.high)
                    if data.high(i) > data.predlow(i)
                        numearn = numearn + 1;
                    end
                end
                res = numearn / numel(data.high);
                a = numearn;
                b = numel(data.high) - numearn;
            end
            % 实际稳赢率
            function [res a b] = surelywin(data)
                numearn = 0;
                for i = 1 : numel(data.high)
                    if data.low(i) > data.buyprice(i)
                        numearn = numearn + 1;
                    end
                end
                res = numearn / numel(data.high);
                a = numearn;
                b = numel(data.high) - numearn;
            end
            
            function res = numstar(p)
                if isnan(p)
                    res = 'N/A';
                elseif p > 0.1
                    res = app.hand.lang.lang{94,app.hand.langid};
                elseif p > 0.05
                    res = '#    (p ≤ 0.1)';
                elseif p > 0.01
                    res = '*    (p ≤ 0.05)';
                elseif p > 0.001
                    res = '**   (p ≤ 0.01)';
                else
                    res = '***  (p ≤ 0.001)';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Hystudio Stock Helper';
            app.hand.show = struct();
            
            % 初始化坐标系
            for i = 1 : 10
                app.initshow(i);
            end      
            
            % 初始化进度条
            position = get(app.Progressbar,'position');
            position(3) = 0;
            set(app.Progressbar,'position',position);
            position2 = get(app.Progressbar2,'position');
            position2(3) = 0;
            set(app.Progressbar2,'position',position2);
            
            % 初始化列
            app.hand.keystock = load('src/keystock.mat');
            app.hand.csstock = load('src/csstock.mat');
            para = load('src/stocklist.mat');
            app.hand.stocklist = para.stocklist;
            stocklistitems = cell(1,numel(app.hand.stocklist.code));
            stocklistitemsdata = 1:numel(app.hand.stocklist.code);
            for i = 1 : numel(app.hand.stocklist.code)
                stocklistitems{i} = ['[' app.hand.stocklist.code{i} ']  ' app.hand.stocklist.name{i} ' (' app.hand.stocklist.shsz{i} ')  < ' app.hand.stocklist.industry{i} ' >' ];
            end
            set(app.StockList,'items',stocklistitems);
            set(app.StockList,'itemsData',stocklistitemsdata);
            app.hand.stklist.items = stocklistitems;
            app.hand.stklist.itemsdata = stocklistitemsdata;
            
            set(app.CSList,'items',{});
            set(app.CSList,'itemsData',{});
            app.hand.cslist.items = {};
            app.hand.cslist.itemsdata = [];
            
            app.putinlist('kms',app.hand.keystock.stockcode);
            app.putinlist('cs',app.hand.csstock.stockcode);

            
            set(app.StockPoolList,'items',get(app.CSList,'items'));
            set(app.StockPoolList,'itemsdata',get(app.CSList,'itemsdata'));
            
            app.hand.poolindex = 0;

            sumdata_suggestion = load('src/suggestion_sum.mat');
            sumdata_deprecation = load('src/deprecation_sum.mat');
            sumdata_neutral = load('src/neutral_sum.mat');
            sumdata_control = load('src/control_sum.mat');
            
            app.sum.suggestion = sumdata_suggestion.sum;
            app.sum.deprecation = sumdata_deprecation.sum;
            app.sum.neutral = sumdata_neutral.sum;
            app.sum.control = sumdata_control.sum;
            
            set(app.SumTableSuggestion,'data',sumdata_suggestion.sumtable);
            set(app.SumTableDeprecation,'data',sumdata_deprecation.sumtable);
            set(app.SumTableNeutral,'data',sumdata_neutral.sumtable);
            set(app.SumTableControlRandom,'data',sumdata_control.sumtable);
            
            % 加载语言包
            app.hand.lang = load('src/lang.mat');
            app.hand.langid = 1;
            
            app.hand.groupRe = [true true true true];
            
            app.syncdata();
            app.updatesum();
        end

        % Value changed function: RealTimeButton
        function RealTimeButtonValueChanged(app, event)
            while app.RealTimeButton.Value
                stockinfo = dz_getrealtimewebstockdata(app.hand.keystock.stockcode);
                for i = 1 : numel(stockinfo)
                    show = app.getshow(i);
                    set(show.rtlamp,'color',[1,0,0]); 
                    istockinfo = stockinfo{i};
                    iprice = str2double(istockinfo.currentprice);
                    ilow = str2double(istockinfo.lowprice);
                    ihigh = str2double(istockinfo.highprice);
                    set(show.mark,'ydata',iprice);
                    set(show.rect,'position',[0.5 ilow 1 ihigh-ilow]);
                    set(show.nameLabel,'text',istockinfo.stockname,'fontcolor',[1,0,0]);
                    set(show.codeLabel,'text',istockinfo.stockcode,'fontcolor',[1,0,0]);
                    set(show.realTimeLabel,'text',istockinfo.time,'fontcolor',[1,0,0]); 
                    
                    drawnow
                    pause(0.2);
                    app.setProgressbar(i/numel(stockinfo));
                    
                    set(show.nameLabel,'text',istockinfo.stockname,'fontcolor',[0,0,0]);
                    set(show.codeLabel,'text',istockinfo.stockcode,'fontcolor',[0,0,0]);
                    set(show.realTimeLabel,'text',istockinfo.time,'fontcolor',[0,0,0]); 
                    set(show.rtlamp,'color',[0,1,0]); 
                end
                pause(5);
            end
        end

        % Button pushed function: PredictionButton
        function PredictionButtonPushed(app, event)

            app.hand.preddata = load('src/preddata.mat');
            lastpredday = app.hand.preddata.time;
            lastpredkeyid = app.hand.preddata.keyid;
            % 判断即将预测的数据的时间
            webstockdata = dz_getwebstockdata('601155','20191212',char(datetime('yesterday','format','yyyyMMdd')-days(1)));
            predday = dz_nextworkday(datetime(webstockdata.date{end}),2,'yyyyMMdd');
            if strcmp(char(predday(end)),lastpredday) && strcmp(app.hand.keystock.keyid,lastpredkeyid)
                value = app.hand.preddata.value; 
                for i = 1 : size(value,1)
                    show = app.getshow(i);
                    set(show.lamp,'color',[1 0 0]);
                    set(show.line1,'ydata',[value(i,1) value(i,1)]);
                    set(show.line2,'ydata',[value(i,2) value(i,2)]);
                    set(show.lamp,'color',[0 0 0]);
                    set(show.dateLabel,'text',lastpredday);
                    set(show.predCodeLabel,'text',app.hand.preddata.stockcode{i});
                    set(show.lamp,'color',[0 1 0]);
                    drawnow
                end
                if ~get(app.RePredCheckBox,'value')
                    return;
                end
            end
            
            value = zeros(numel(app.hand.keystock.stockcode),2);
            time = '';
            for i = 1 : numel(app.hand.keystock.stockcode)
                show = app.getshow(i);
                set(show.lamp,'color',[1 0 0]);
                [cred,predstockdata] = ddz_stockpred(app.hand.keystock.stockcode{i},@app.callback,true);
                prices = dz_getfeats(predstockdata,'low','high');
                low = prices(1,2);
                high = prices(2,2);
                value(i,1) = low;
                value(i,2) = high;
                set(show.line1,'ydata',[low low]);
                set(show.line2,'ydata',[high high]);
                set(show.dateLabel,'text',predstockdata.date{2});
                set(show.predCodeLabel,'text',app.hand.keystock.stockcode{i});
                set(show.lamp,'color',[0 1 0]);
                time = char(datetime(predstockdata.date{2},'format','yyyyMMdd'));
                drawnow
            end
            keyid = app.hand.keystock.keyid;
            stockcode = app.hand.keystock.stockcode;
            save('src/preddata.mat','keyid','time','stockcode','value');
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            keyword = get(app.SearchField,'value');
            if numel(keyword)
                searchedlist = {};
                searcheddata = [];
                for i = 1 : numel(app.hand.stklist.items)
                    if ~isempty(regexp(app.hand.stklist.items{i},keyword))
                        searchedlist = [searchedlist app.hand.stklist.items{i}];
                        searcheddata = [searcheddata i];
                    end
                end
                set(app.StockList,'items',searchedlist);
                set(app.StockList,'itemsData',searcheddata);
            else
                set(app.StockList,'items',app.hand.stklist.items);
                set(app.StockList,'itemsData',app.hand.stklist.itemsdata);
            end
        end

        % Button pushed function: AddCSButton
        function AddCSButtonPushed(app, event)
            stockid = get(app.StockList,'value');
            if ismember(stockid,app.hand.cslist.itemsdata)
                return;
            end
            app.hand.cslist.itemsdata = [app.hand.cslist.itemsdata stockid];
            app.hand.cslist.items = [app.hand.cslist.items app.hand.stklist.items{stockid}];
            set(app.CSList,'items',app.hand.cslist.items);
            set(app.CSList,'itemsdata',app.hand.cslist.itemsdata);
        end

        % Button pushed function: AddKMSButton
        function AddKMSButtonPushed(app, event)
            if numel(get(app.KMSList,'itemsdata')) >= 10
               return; 
            end
            stockid = get(app.StockList,'value');
            if ismember(stockid,app.hand.kmslist.itemsdata)
                return;
            end
            app.hand.kmslist.itemsdata = [app.hand.kmslist.itemsdata stockid];
            app.hand.kmslist.items = [app.hand.kmslist.items app.hand.stklist.items{stockid}];
            set(app.KMSList,'items',app.hand.kmslist.items);
            set(app.KMSList,'itemsdata',app.hand.kmslist.itemsdata);
            if numel(get(app.KMSList,'itemsdata')) >= 10
               set(app.StartButton,'enable','on');
            else
               set(app.StartButton,'enable','off');
            end 
        end

        % Button pushed function: RemoveCSButton
        function RemoveCSButtonPushed(app, event)
            stockid = get(app.CSList,'value');
            if isempty(stockid)
                return;
            end
            index = find(app.hand.cslist.itemsdata==stockid);
            app.hand.cslist.itemsdata(index) = [];
            app.hand.cslist.items(index) = [];
            set(app.CSList,'itemsdata',app.hand.cslist.itemsdata);
            set(app.CSList,'items',app.hand.cslist.items);
        end

        % Button pushed function: RemoveKMSButton
        function RemoveKMSButtonPushed(app, event)
            stockid = get(app.KMSList,'value');
            if isempty(stockid)
                return;
            end
            index = find(app.hand.kmslist.itemsdata==stockid);
            app.hand.kmslist.itemsdata(index) = [];
            app.hand.kmslist.items(index) = [];
            set(app.KMSList,'itemsdata',app.hand.kmslist.itemsdata);
            set(app.KMSList,'items',app.hand.kmslist.items);
            if numel(app.KMSList,'itemsdata') >= 10
               set(app.StartButton,'enable','on');
            else
               set(app.StartButton,'enable','off');
            end 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            stockid = get(app.KMSList,'itemsdata');
            stockcode = app.hand.stocklist.code(stockid);
            keyid = char(datetime);
            save('src/keystock.mat','stockcode','keyid');
            app.hand.keystock.stockcode = stockcode;
            app.hand.keystock.keyid = keyid;
        end

        % Button pushed function: InPoolButton
        function InPoolButtonPushed(app, event)
            set(app.StockPoolList,'items',get(app.CSList,'items'));
            set(app.StockPoolList,'itemsdata',get(app.CSList,'itemsdata'));
            stockid = get(app.CSList,'itemsdata');
            stockcode = app.hand.stocklist.code(stockid);
            keyid = char(datetime);
            save('src/csstock.mat','stockcode','keyid');
            app.hand.csstock.stockcode = stockcode;
            app.hand.csstock.keyid = keyid;
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
           app.ClearCSButton();
           set(app.CSList,'items',get(app.StockList,'items'));
           set(app.CSList,'itemsdata',get(app.StockList,'itemsdata'));
           app.hand.cslist.items = get(app.StockList,'items');
           app.hand.cslist.itemsdata = get(app.StockList,'itemsdata');
        end

        % Button pushed function: ClearCSButton
        function ClearCSButtonPushed(app, event)
            set(app.CSList,'items',{});
            set(app.CSList,'itemsdata',[]);
            app.hand.cslist.items = {};
            app.hand.cslist.itemsdata = [];
        end

        % Button pushed function: ClearKMSButton
        function ClearKMSButtonPushed(app, event)
            set(app.KMSList,'items',{});
            set(app.KMSList,'itemsdata',[]);
            app.hand.kmslist.items = {};
            app.hand.kmslist.itemsdata = [];
        end

        % Cell selection callback: SuggestionTable
        function SuggestionTableCellSelection(app, event)
            index = event.Indices;
            if index(2) == 1
                data = get(app.SuggestionTable,'data');
                if strcmp(data(index(1),1),'')
                    data{index(1),1} = '√';
                else
                    data{index(1),1} = '';
                end
                set(app.SuggestionTable,'data',data);
            end
        end

        % Button pushed function: AddFromRSButton
        function AddFromRSButtonPushed(app, event)
            
            thandle = app.getTabHandle();
            
            if isempty(thandle)
                return;
            end
        
            tabledata = get(thandle.fromtable,'data');
            
            if isempty(tabledata)
                app.ClearButtonPushed();
            end
            
            index = [];
            for i = 1 : size(tabledata,1)
                if strcmp(tabledata{i,1},'√')
                    index = [index i];
                end
            end
            if ~isempty(index)
                app.ClearButtonPushed();
                datacell = get(thandle.fromtable,'data');
                datacell = datacell(index,:);
                datacell(:,1) = [];
                datacell = [datacell num2cell(ones(size(datacell,1),1))];
                set(app.BuyTable,'data',datacell);
            end
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            stockid = get(app.PurchaseCartTable,'value');
            if isempty(stockid)
                return;
            end
            itemsdata = get(app.PurchaseCartTable,'itemsdata');
            items = get(app.PurchaseCartTable,'items');
            index = find(itemsdata==stockid);
            itemsdata(index) = [];
            items(index) = [];
            set(app.PurchaseCartTable,'itemsdata',itemsdata);
            set(app.PurchaseCartTable,'items',items);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            set(app.BuyTable,'data',{});
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            numitems = zeros(1,4);
            while app.RunButton.Value
                if ~sum(app.hand.groupRe)
                    set(app.RunButton,'value',false);
                    return;
                end
                
                if strcmp(app.RollBackOneDayDebugModeMenu.Checked,'on')
                    rollback = true;
                else
                    rollback = false;
                end

                
                itemsdata = get(app.StockPoolList,'itemsdata');
                if app.hand.poolindex + 1 > numel(itemsdata)
                    if strcmp(app.AutoSaveRecommendationListMenu.Checked,'on')
                        app.SaveDataButtonPushed();
                    end
                    set(app.RunButton,'value',false);
                    return;
                end
                
                if sum(app.hand.groupRe(1:3))
                
                    nextindex = app.hand.poolindex + 1;
                    istockid = itemsdata(nextindex);
                    set(app.StockPoolList,'value',istockid);
                    scroll(app.StockPoolList,istockid);
                    
                    istockcode = app.hand.stocklist.code{istockid};


                    try
                        [cred,predstockdata] = ddz_stockpred(istockcode,@app.callback2,rollback);
                    catch
                        app.hand.poolindex = nextindex;
                        continue;
                    end
                    if isempty(predstockdata) || cred < 0.5
                        app.hand.poolindex = nextindex;
                        continue;
                    end
                    prices = dz_getfeats(predstockdata,'low','high');
                    low = prices(1,2);
                    high = prices(2,2);
                    preddate = predstockdata.date{2};
                    
                    if rollback
                        cprice = (prices(1,1) + prices(2,1))/2;
                    else
                        istockinfo = dz_getrealtimewebstockdata(istockcode);
                        cprice = str2double(istockinfo.currentprice);
                    end
                    
                    if low > cprice && size(get(app.SuggestionTable,'data'),1) < get(app.MaxEditField,'value') && app.hand.groupRe(1)
                        datacell = get(app.SuggestionTable,'data');
                        itemexist = false;
                        for i = 1 : size(datacell,1)
                            if istockid == datacell{i,2}
                                datacell(i,:) = {'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,(low-cprice)*100/cprice,preddate};
                                itemexist = true;
                                break;
                            end
                        end
                        if ~itemexist
                            datacell = [datacell;{'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,(low-cprice)*100/cprice,preddate}];
                        end
                        set(app.SuggestionTable,'data',datacell);
                        numitems(1) = size(get(app.SuggestionTable,'data'),1);
                    end
                    if  high < cprice && size(get(app.DeprecationTable,'data'),1) < get(app.MaxEditField,'value') && app.hand.groupRe(2)
                        datacell = get(app.DeprecationTable,'data');
                        itemexist = false;
                        for i = 1 : size(datacell,1)
                            if istockid == datacell{i,2}
                                datacell(i,:) = {'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,(high-cprice)*100/cprice,preddate};
                                itemexist = true;
                                break;
                            end
                        end
                        if ~itemexist
                            datacell = [datacell;{'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,(high-cprice)*100/cprice,preddate}];
                        end
                        set(app.DeprecationTable,'data',datacell);
                        numitems(2) = size(get(app.DeprecationTable,'data'),1);
                    end
                    if  high >= cprice && low <= cprice && size(get(app.NeutralTable,'data'),1) < get(app.MaxEditField,'value') && app.hand.groupRe(3)
                        datacell = get(app.NeutralTable,'data');
                        itemexist = false;
                        for i = 1 : size(datacell,1)
                            if istockid == datacell{i,2}
                                datacell(i,:) = {'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,((high+low)/2-cprice)*100/cprice,preddate};
                                itemexist = true;
                                break;
                            end
                        end
                        if ~itemexist
                            datacell = [datacell;{'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,high,((high+low)/2-cprice)*100/cprice,preddate}];
                        end
                        set(app.NeutralTable,'data',datacell);
                        numitems(3) = size(get(app.NeutralTable,'data'),1);
                    end
                
                end
                if  app.hand.groupRe(4) && (sum(app.hand.groupRe == [false false false true]) == 4 || (size(get(app.DeprecationTable,'data'),1) >= get(app.MaxEditField,'value') && size(get(app.SuggestionTable,'data'),1) >= get(app.MaxEditField,'value') && size(get(app.NeutralTable,'data'),1) >= get(app.MaxEditField,'value')))
                    % 达到数量要求
                    
                    indexs = randperm(numel(itemsdata),min(get(app.MaxEditField,'value')*2,numel(itemsdata)));
                    stockids = itemsdata(indexs);
                    stockcodes = app.hand.stocklist.code(stockids);
                    datacell = {};
                    for i = 1 : numel(stockids)
                        
                        if ~app.RunButton.Value
                            break;
                        end
                        
                        stockid = stockids(i);
                        set(app.StockPoolList,'value',stockid);
                        stockcode = stockcodes{i};
                        scroll(app.StockPoolList,stockid);
                        try
                            stockinfos = dz_getrealtimewebstockdata(stockcode);
                            
                        catch
                            continue;
                        end
                        if ~isstruct(stockinfos)
                            continue;
                        end
                        try 
                            [cred,predstockdata] = ddz_stockpred(stockcodes{i},@app.callback2,rollback);
                        catch
                            continue;
                        end
                        if isempty(predstockdata) || cred < 0.5
                            continue;
                        end
                        prices = dz_getfeats(predstockdata,'low','high');
                        low = prices(1,2);
                        high = prices(2,2);
                        preddate = predstockdata.date{2};
                        
                        if rollback
                            currentprice = (prices(1,1) + prices(2,1))/2;
                        else
                            currentprice = str2double(stockinfos.currentprice);
                        end
                        
                        
                        amp = ((low + high)/2 - currentprice)*100/currentprice;
                        datacell = [datacell;{'√',stockid,app.hand.stocklist.name{stockid},stockcode,currentprice,low,high,amp,preddate}];
                        app.ControlTable.Data = datacell;
                        numitems(4) = size(get(app.ControlTable,'data'),1);
                        if size(datacell,1) >= get(app.MaxEditField,'value')
                            break;
                        end
                    end
                    
                    if strcmp(app.AutoSaveRecommendationListMenu.Checked,'on')
                        app.SaveDataButtonPushed();
                    end
                    set(app.RunButton,'value',false);
                    return;
                end
                app.SugNLabel.Text = num2str(numitems(1));
                app.DepNLabel.Text = num2str(numitems(2));
                app.NeuNLabel.Text = num2str(numitems(3));
                app.ConNLabel.Text = num2str(numitems(4));
                if mod(sum(numitems),5) == 0
                    if strcmp(app.AutoSaveRecommendationListMenu.Checked,'on')
                        app.SaveDataButtonPushed();
                        app.LastAutoSaveMenu.Text = [app.hand.lang.lang{105,app.hand.langid} char(datetime('now'))];
                    end
                end
                try
                    app.hand.poolindex = nextindex;
                catch
                end
            end
        end

        % Button pushed function: ResetButtonRecommender
        function ResetButtonRecommenderPushed(app, event)
            if ~get(app.UnLockButton,'value')
                return;
            end
            app.hand.poolindex = 0;
            set(app.UnLockButton,'value',false);
        end

        % Button pushed function: PurchaseButton
        function PurchaseButtonPushed(app, event)
            cost = 0;
            buytable = {};
            tabledata = get(app.BuyTable,'data');
            for i = 1 : size(tabledata,1)
                data = tabledata(i,:);
                
                if strcmp(app.RollBackOneDayDebugModeMenu.Checked,'on')
                    SN = [char(datetime('now','format','yyyyMMddHHmmss')-days(1)) num2str(i)];
                    dt = char(datetime()-days(1));
                else
                    SN = [char(datetime('now','format','yyyyMMddHHmmss')) num2str(i)];
                    dt = char(datetime());
                end
                
                buydata = {SN data{1:4} data{4} data{4} data{4} dt data{5:6} data{8:9} 0};
                buytable = [buytable;buydata];
                cost = cost + data{4} * data{9} * 100;
            end
            
            thandle = app.getTabHandle();
            
            sumtable = get(thandle.table,'data');
            set(thandle.table,'data',[sumtable;buytable]);
            thandle.sum.invest = thandle.sum.invest + cost;
            thandle.sum.account = thandle.sum.account - cost;
            app.setTabHandle(thandle.tabname,thandle.sum);
            app.updatesum();
            app.syncdata();
        end

        % Cell selection callback: SellTable
        function SellTableCellSelection(app, event)
            index = event.Indices;
            if index(2) == size(app.SellTable.Data,2)
                data = get(app.SellTable,'data');
                if strcmp(data(index(1),end),'')
                    data{index(1),end} = '√';
                else
                    data{index(1),end} = '';
                end
                set(app.SellTable,'data',data);
            end
        end

        % Button pushed function: SellButton
        function SellButtonPushed(app, event)
            set(app.TradeMonitorSwitch,'value','Off');
            pause(5);
            selltable = get(app.SellTable,'data');
            
            thandle = app.getTabHandle();
            
            sumtable = get(thandle.table,'data');
            index = [];
            for i = 1 : size(selltable,1)
                if strcmp(selltable{i,end},'√') && selltable{i,9}<=selltable{i,8}
                    index = [index i];
                end
            end
            tosell = selltable(index,:);
            returnsum = 0;
            for i = 1 : size(tosell,1)
                serial = tosell{i,1};
                price = tosell{i,5};
                bill = tosell{i,9};
                returnsum = returnsum + price * bill * 100;
                
                serindex = find(strcmp(sumtable(:,1),serial));
                sumtable{serindex,13} = sumtable{serindex,13} - bill;
            end
            keepindex = [];
            for i = 1 : size(sumtable,1)
                if sumtable{i,13} > 0
                    keepindex = [keepindex i];
                end
            end
            sumtable = sumtable(keepindex,:);
            set(thandle.table,'data',sumtable);
            
            thandle.sum.return = thandle.sum.return + returnsum;
            thandle.sum.account = thandle.sum.account + returnsum;
                    
            app.setTabHandle(thandle.tabname,thandle.sum);
            
            app.updatesum();
            app.syncdata();
            set(app.TradeMonitorSwitch,'value','On');
            app.TradeMonitorSwitchValueChanged();
        end

        % Value changed function: TradeMonitorSwitch
        function TradeMonitorSwitchValueChanged(app, event)
            while strcmp(app.TradeMonitorSwitch.Value,'On')
                if strcmp(app.PriceBacthUpdateMenu.Checked,'on')
                    sumupdate(app.getTabHandle('Suggestion'),true);
                    sumupdate(app.getTabHandle('Deprecation'),true);
                    sumupdate(app.getTabHandle('Neutral'),true);
                    sumupdate(app.getTabHandle('Control (Random)'),true);
                    
                else
                    thandle = app.getTabHandle();
                    if isempty(thandle)
                        app.TradeMonitorSwitch.Value = 'Off';
                        return;
                    end
                    sumupdate(thandle,false);
                    
                end
                app.calPvalue();
                app.plotFigs();
                pause(5);
            end
            
            function sumupdate(thandle,batch)
                if batch
                    sumtable = get(thandle.table,'data');
                    if ~isempty(sumtable)
                        numstocks = size(sumtable,1);
                        numincrease = 0;
                        sellsum = 0;
                        for i = 1 : size(sumtable,1)
                            code = sumtable{i,4};
                            try
                                stockinfo = dz_getrealtimewebstockdata(code);
                            catch
                                continue;
                            end
                            price = str2double(stockinfo.currentprice);
                            
                            high = str2double(stockinfo.highprice);
                            low = str2double(stockinfo.lowprice);
                            
                            
                            sumtable{i,5} = price;
                            sumtable{i,6} = low;
                            sumtable{i,7} = high;
                            sumtable{i,14} = (price - sumtable{i,8}) * sumtable{i,13} * 100;
                            if price > sumtable{i,8}
                                numincrease = numincrease + 1;
                            end  
                            sellsum = sellsum +  price * sumtable{i,13} * 100;
                            
                            
                            try
                                app.setProgressbar3(i/numstocks);
                            catch e
                                
                            end
                        end
                        set(thandle.table,'data',sumtable); 
                        thandle.sum.total = thandle.sum.account + sellsum;
                        thandle.sum.income = thandle.sum.total - thandle.sum.initial;
                        thandle.sum.numincrease = numincrease;
                        thandle.sum.numall = numstocks;
                        thandle.sum.tabdata = sumtable;
                        app.setTabHandle(thandle.tabname,thandle.sum);
                        app.updatesum();
                        app.syncdata();
                        set(thandle.increaseLabel,'text',[num2str(numincrease) ' / ' num2str(numstocks)]);
                        set(thandle.increaseRateLabel,'text',[num2str(round(numincrease*100/numstocks,1)) ' %']);
                        app.localize(thandle.tabname,sumtable,thandle.sum);
                        
                        
                    end
                else 
                    sumtable = get(thandle.table,'data');   
                    if ~isempty(sumtable)
                        numstocks = size(sumtable,1);
                        numincrease = 0;
                        sellsum = 0;
                        for i = 1 : size(sumtable,1)
                            code = sumtable{i,4};
                            try
                                stockinfo = dz_getrealtimewebstockdata(code);
                            catch
                                continue;
                            end
                            price = str2double(stockinfo.currentprice);
                            
                            high = str2double(stockinfo.highprice);
                            low = str2double(stockinfo.lowprice);
                            
                            sumtable{i,5} = price;
                            sumtable{i,6} = low;
                            sumtable{i,7} = high;
                            sumtable{i,14} = (price - sumtable{i,8}) * sumtable{i,13} * 100;
                            if price > sumtable{i,8}
                                numincrease = numincrease + 1;
                            end
                            set(thandle.table,'data',sumtable);                            
                            sellsum = sellsum +  price * sumtable{i,13} * 100;
                            
                            app.setProgressbar3(i/numstocks);
                            
%                             pause(0.1);
                        end                      
                        thandle.sum.total = thandle.sum.account + sellsum;
                        thandle.sum.income = thandle.sum.total - thandle.sum.initial;   
                        thandle.sum.numincrease = numincrease;
                        thandle.sum.numall = numstocks;
                        thandle.sum.tabdata = sumtable;
                        app.setTabHandle(thandle.tabname,thandle.sum);                       
                        app.updatesum();
                        app.syncdata();                        
                        set(thandle.increaseLabel,'text',[num2str(numincrease) ' / ' num2str(numstocks)]);
                        set(thandle.increaseRateLabel,'text',[num2str(round(numincrease*100/numstocks,1)) ' %']);
                        app.localize(thandle.tabname,sumtable,thandle.sum);
                    end
                end
            end
            
        end

        % Button pushed function: ResetButtonMonitor
        function ResetButtonMonitorPushed(app, event)
            if ~get(app.UnLockButton,'value')
                return;
            end
            sumi.suggestion.total = 1000000;
            sumi.suggestion.account = 1000000;
            sumi.suggestion.income = 0;
            sumi.suggestion.invest = 0;
            sumi.suggestion.return = 0;
            sumi.suggestion.initial = 1000000;
            
            sumi.deprecation.total = 1000000;
            sumi.deprecation.account = 1000000;
            sumi.deprecation.income = 0;
            sumi.deprecation.invest = 0;
            sumi.deprecation.return = 0;
            sumi.deprecation.initial = 1000000;
            
            sumi.neutral.total = 1000000;
            sumi.neutral.account = 1000000;
            sumi.neutral.income = 0;
            sumi.neutral.invest = 0;
            sumi.neutral.return = 0;
            sumi.neutral.initial = 1000000;
            
            sumi.control.total = 1000000;
            sumi.control.account = 1000000;
            sumi.control.income = 0;
            sumi.control.invest = 0;
            sumi.control.return = 0;
            sumi.control.initial = 1000000;
            
            app.sum = sumi;
            
            app.updatesum();
            
            set(app.SumTableSuggestion,'data',{});
            set(app.SumTableDeprecation,'data',{});
            set(app.SumTableNeutral,'data',{});
            set(app.SumTableControlRandom,'data',{});
            
            app.syncdata();
            
            app.localize('Suggestion',{},sumi.suggestion);
            app.localize('Deprecation',{},sumi.deprecation);
            app.localize('Neutral',{},sumi.neutral);
            app.localize('Control (Random)',{},sumi.control);
            
            set(app.UnLockButton,'value',false);
        end

        % Button pushed function: AddCandidatesButton
        function AddCandidatesButtonPushed(app, event)
            stockid = get(app.CSList,'itemsdata');
            if ~isempty(stockid)
                app.ClearButtonPushed();
                datacell = cell(numel(stockid),6);
                for i = 1 : numel(stockid)
                    datacell{i,1} = stockid(i);
                    datacell{i,2} = app.hand.stocklist.name{stockid(i)};
                    datacell{i,3} = app.hand.stocklist.code{stockid(i)};
                    datacell{i,4} = str2double(getfield(dz_getrealtimewebstockdata(app.hand.stocklist.code{stockid(i)}),'currentprice'));
                    datacell{i,5} = '--';
                    datacell{i,6} = '--';
                    datacell{i,7} = '--';
                    datacell{i,8} = '--';
                    datacell{i,9} = 1;
                end
                set(app.BuyTable,'data',datacell);
            end
        end

        % Button pushed function: AddRecommandTableButton
        function AddRecommandTableButtonPushed(app, event)
            if ~isempty(app.StockPoolList.ItemsData)
                stockid = app.StockPoolList.Value;
                chand = app.getRecommandTabHandle();
                
                stockcode = app.hand.stocklist.code{stockid};
                
                stockinfo = dz_getrealtimewebstockdata(stockcode);
                cprice = str2double(stockinfo.currentprice);
                
                    datacell = get(chand.table,'data');
                    itemexist = false;
                    for i = 1 : size(datacell,1)
                        if stockid == datacell{i,2}
                            datacell(i,:) = {'√',stockid,app.hand.stocklist.name{stockid},app.hand.stocklist.code{stockid},cprice,'--','--','--','--'};
                            itemexist = true;
                            break;
                        end
                    end
                    if ~itemexist
                        datacell = [datacell;{'√',stockid,app.hand.stocklist.name{stockid},app.hand.stocklist.code{stockid},cprice,'--','--','--','--'}];
                    end
                    set(chand.table,'data',datacell);
            end
        end

        % Menu selected function: T1TradingRealMenu
        function T1TradingRealMenuSelected(app, event)
            if strcmp(app.T1TradingRealMenu.Checked,'on')
                app.T1TradingRealMenu.Checked = 'off';
            else
                 app.T1TradingRealMenu.Checked = 'on';
            end
        end

        % Selection change function: SumTabGroup
        function SumTabGroupSelectionChanged(app, event)
            app.syncdata();
            app.AddFromRSButtonPushed();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            set(app.SaveDataButton,'fontcolor',[1 0 0]);
            set(app.SaveDataButton,'text',app.hand.lang.lang{92,app.hand.langid});
            drawnow;
            
            sugtable = app.SuggestionTable.Data;
            deptable = app.DeprecationTable.Data;
            neutable = app.NeutralTable.Data;
            contable = app.ControlTable.Data;

            root.suggestion = tabstc(sugtable);
            root.deprecation = tabstc(deptable);
            root.neutral = tabstc(neutable);
            root.control = tabstc(contable);
            
            jsontext = jsonencode(root);
            dz_writetext('src/predstocks.json',jsontext);

            set(app.SaveDataButton,'fontcolor',[0 0 0]);
            set(app.SaveDataButton,'text',app.hand.lang.lang{46,app.hand.langid});
            drawnow;
            
            function remcell = tabstc(remtable)
                remcell = cell(1,size(remtable,1));
                for i = 1 : size(remtable,1)
                remdata.id = remtable{i,2};
                remdata.name = remtable{i,3};
                remdata.code = remtable{i,4};
                remdata.price = remtable{i,5};
                remdata.pred_low_price = remtable{i,6};
                remdata.pred_high_price = remtable{i,7};
                remdata.amplitude = remtable{i,8};
                remdata.pred_date = remtable{i,9};
                remcell{i} = remdata;
                end
            end
        end

        % Value changed function: StockList
        function StockListValueChanged(app, event)
            stockid = app.StockList.Value;
            stockcode = app.hand.stocklist.code{stockid};
            today = datetime('today','format','yyyyMMdd');
            fromday = today - days(180);
            stockdata = dz_getwebstockdata(stockcode,char(fromday),char(today));
            cla(app.KlineShower);
            dz_kline(stockdata,app.KlineShower);
        end

        % Value changed function: CSList
        function CSListValueChanged(app, event)
            stockid = app.CSList.Value;
            stockcode = app.hand.stocklist.code{stockid};
            today = datetime('today','format','yyyyMMdd');
            fromday = today - days(180);
            stockdata = dz_getwebstockdata(stockcode,char(fromday),char(today));
            cla(app.KlineShower);
            dz_kline(stockdata,app.KlineShower);
        end

        % Value changed function: KMSList
        function KMSListValueChanged(app, event)
            stockid = app.KMSList.Value;
            stockcode = app.hand.stocklist.code{stockid};
            today = datetime('today','format','yyyyMMdd');
            fromday = today - days(180);
            stockdata = dz_getwebstockdata(stockcode,char(fromday),char(today));
            cla(app.KlineShower);
            dz_kline(stockdata,app.KlineShower);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            set(app.LoadDataButton,'fontcolor',[1 0 0]);
            set(app.LoadDataButton,'text',app.hand.lang.lang{92,app.hand.langid});  
            drawnow
        
            predstocks = jsondecode(dz_readtext('src/predstocks.json'));
            
            loadtotable(app.SuggestionTable,predstocks.suggestion);
            loadtotable(app.DeprecationTable,predstocks.deprecation);
            loadtotable(app.NeutralTable,predstocks.neutral);
            loadtotable(app.ControlTable,predstocks.control);
            
            
            set(app.LoadDataButton,'fontcolor',[0 0 0]);
            set(app.LoadDataButton,'text',app.hand.lang.lang{47,app.hand.langid});
            drawnow
            
            function loadtotable(tablehand,preddata)
                len = numel(preddata);
                predtable = cell(len,9);
                for i = 1 : len
                    predtable{i,1} = '√';
                    predtable{i,2} = getpart(preddata(i),'id');
                    predtable{i,3} = getpart(preddata(i),'code');
                    predtable{i,4} = getpart(preddata(i),'name');
                    predtable{i,5} = getpart(preddata(i),'price');
                    predtable{i,6} = getpart(preddata(i),'pred_low_price');
                    predtable{i,7} = getpart(preddata(i),'pred_high_price');
                    predtable{i,8} = getpart(preddata(i),'amplitude');
                    predtable{i,9} = getpart(preddata(i),'pred_date');
                end
                set(tablehand,'data',predtable);
            end
            
            function res = getpart(stc,field)
                if isfield(stc,field)
                    res = getfield(stc,field);
                else
                    res = '--';
                end
            end
        end

        % Menu selected function: PriceBacthUpdateMenu
        function PriceBacthUpdateMenuSelected(app, event)
            if strcmp(app.PriceBacthUpdateMenu.Checked,'on')
                app.PriceBacthUpdateMenu.Checked = 'off';
            else
                 app.PriceBacthUpdateMenu.Checked = 'on';
            end
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.changeLang('English');
            app.EnglishMenu.Checked = 'on';
            app.ChineseMenu.Checked = 'off';
            app.JapaneseMenu.Checked = 'off';
            app.RussianMenu.Checked = 'off';
        end

        % Menu selected function: ChineseMenu
        function ChineseMenuSelected(app, event)
            app.changeLang('Chinese');
            app.EnglishMenu.Checked = 'off';
            app.ChineseMenu.Checked = 'on';
            app.JapaneseMenu.Checked = 'off';
            app.RussianMenu.Checked = 'off';
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            items = app.CSList.Items;
            itemsdata = app.CSList.ItemsData;
            indexs = randperm(numel(itemsdata));
            app.CSList.Items = items(indexs);
            app.CSList.ItemsData = itemsdata(indexs);
        end

        % Menu selected function: RollBackOneDayDebugModeMenu
        function RollBackOneDayDebugModeMenuSelected(app, event)
            if strcmp(app.RollBackOneDayDebugModeMenu.Checked,'on')
                app.RollBackOneDayDebugModeMenu.Checked = 'off';
            else
                app.RollBackOneDayDebugModeMenu.Checked = 'on';
            end
        end

        % Menu selected function: DiscardRecommendationListMenu
        function DiscardRecommendationListMenuSelected(app, event)
            app.SuggestionTable.Data = {};
            app.DeprecationTable.Data = {};
            app.NeutralTable.Data = {};
            app.ControlTable.Data = {};
        end

        % Menu selected function: AutoSaveRecommendationListMenu
        function AutoSaveRecommendationListMenuSelected(app, event)
            if strcmp(app.AutoSaveRecommendationListMenu.Checked,'on')
                app.AutoSaveRecommendationListMenu.Checked = 'off';
                app.LastAutoSaveMenu.Visible = 'off';
            else
                app.AutoSaveRecommendationListMenu.Checked = 'on';
                app.LastAutoSaveMenu.Visible = 'on';
            end
        end

        % Menu selected function: JapaneseMenu
        function JapaneseMenuSelected(app, event)
            app.changeLang('Japanese');
            app.EnglishMenu.Checked = 'off';
            app.ChineseMenu.Checked = 'off';
            app.JapaneseMenu.Checked = 'on';
            app.RussianMenu.Checked = 'off';
        end

        % Menu selected function: RussianMenu
        function RussianMenuSelected(app, event)
            app.changeLang('Russian');
            app.EnglishMenu.Checked = 'off';
            app.ChineseMenu.Checked = 'off';
            app.JapaneseMenu.Checked = 'off';
            app.RussianMenu.Checked = 'on';
        end

        % Menu selected function: SuggestionMenu
        function SuggestionMenuSelected(app, event)
            if strcmp(app.SuggestionMenu.Checked,'on')
                app.SuggestionMenu.Checked = 'off';
                app.hand.groupRe(1) = false;
            else
                app.SuggestionMenu.Checked = 'on';
                app.hand.groupRe(1) = true;
            end
        end

        % Menu selected function: DeprecationMenu
        function DeprecationMenuSelected(app, event)
            if strcmp(app.DeprecationMenu.Checked,'on')
                app.DeprecationMenu.Checked = 'off';
                app.hand.groupRe(2) = false;
            else
                app.DeprecationMenu.Checked = 'on';
                app.hand.groupRe(2) = true;
            end
        end

        % Menu selected function: NeutralMenu
        function NeutralMenuSelected(app, event)
            if strcmp(app.NeutralMenu.Checked,'on')
                app.NeutralMenu.Checked = 'off';
                app.hand.groupRe(3) = false;
            else
                app.NeutralMenu.Checked = 'on';
                app.hand.groupRe(3) = true;
            end
        end

        % Menu selected function: ControlRandomMenu
        function ControlRandomMenuSelected(app, event)
            if strcmp(app.ControlRandomMenu.Checked,'on')
                app.ControlRandomMenu.Checked = 'off';
                app.hand.groupRe(4) = false;
            else
                app.ControlRandomMenu.Checked = 'on';
                app.hand.groupRe(4) = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveRecommendationDataDefaultMenu = uimenu(app.SaveMenu);
            app.SaveRecommendationDataDefaultMenu.Text = 'Save Recommendation Data (Default)';
            app.SaveRecommendationDataSpecifiedMenu = uimenu(app.SaveMenu);
            app.SaveRecommendationDataSpecifiedMenu.Text = 'Save Recommendation Data (Specified)';
            app.SaveTradingDataDefaultMenu = uimenu(app.SaveMenu);
            app.SaveTradingDataDefaultMenu.Text = 'Save Trading Data  (Default)';
            app.SaveTradingDataSpecifiedMenu = uimenu(app.SaveMenu);
            app.SaveTradingDataSpecifiedMenu.Text = 'Save Trading Data  (Specified)';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.LoadRecommendationDataDefaultMenu = uimenu(app.LoadMenu);
            app.LoadRecommendationDataDefaultMenu.Text = 'Load Recommendation Data  (Default)';
            app.LoadRecommendationDataSpecifiedMenu = uimenu(app.LoadMenu);
            app.LoadRecommendationDataSpecifiedMenu.Text = 'Load Recommendation Data  (Specified)';
            app.LoadTradingDataDefaultMenu = uimenu(app.LoadMenu);
            app.LoadTradingDataDefaultMenu.Text = 'Load Trading Data  (Default)';
            app.LoadTradingDataSpecifiedMenu = uimenu(app.LoadMenu);
            app.LoadTradingDataSpecifiedMenu.Text = 'Load Trading Data  (Specified)';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.T1TradingRealMenu = uimenu(app.OptionsMenu);
            app.T1TradingRealMenu.MenuSelectedFcn = createCallbackFcn(app, @T1TradingRealMenuSelected, true);
            app.T1TradingRealMenu.Checked = 'on';
            app.T1TradingRealMenu.Text = 'T+1 Trading (Real)';
            app.PriceBacthUpdateMenu = uimenu(app.OptionsMenu);
            app.PriceBacthUpdateMenu.MenuSelectedFcn = createCallbackFcn(app, @PriceBacthUpdateMenuSelected, true);
            app.PriceBacthUpdateMenu.Checked = 'on';
            app.PriceBacthUpdateMenu.Text = 'Price Bacth Update';
            app.RollBackOneDayDebugModeMenu = uimenu(app.OptionsMenu);
            app.RollBackOneDayDebugModeMenu.MenuSelectedFcn = createCallbackFcn(app, @RollBackOneDayDebugModeMenuSelected, true);
            app.RollBackOneDayDebugModeMenu.Text = 'Roll Back One Day (Debug Mode)';
            app.RecommendationGroupReceptivenessMenu = uimenu(app.OptionsMenu);
            app.RecommendationGroupReceptivenessMenu.Separator = 'on';
            app.RecommendationGroupReceptivenessMenu.Text = 'Recommendation Group Receptiveness';
            app.SuggestionMenu = uimenu(app.RecommendationGroupReceptivenessMenu);
            app.SuggestionMenu.MenuSelectedFcn = createCallbackFcn(app, @SuggestionMenuSelected, true);
            app.SuggestionMenu.Checked = 'on';
            app.SuggestionMenu.Text = 'Suggestion';
            app.DeprecationMenu = uimenu(app.RecommendationGroupReceptivenessMenu);
            app.DeprecationMenu.MenuSelectedFcn = createCallbackFcn(app, @DeprecationMenuSelected, true);
            app.DeprecationMenu.Checked = 'on';
            app.DeprecationMenu.Text = 'Deprecation';
            app.NeutralMenu = uimenu(app.RecommendationGroupReceptivenessMenu);
            app.NeutralMenu.MenuSelectedFcn = createCallbackFcn(app, @NeutralMenuSelected, true);
            app.NeutralMenu.Checked = 'on';
            app.NeutralMenu.Text = 'Neutral';
            app.ControlRandomMenu = uimenu(app.RecommendationGroupReceptivenessMenu);
            app.ControlRandomMenu.MenuSelectedFcn = createCallbackFcn(app, @ControlRandomMenuSelected, true);
            app.ControlRandomMenu.Checked = 'on';
            app.ControlRandomMenu.Text = 'Control(Random)';
            app.DiscardRecommendationListMenu = uimenu(app.OptionsMenu);
            app.DiscardRecommendationListMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscardRecommendationListMenuSelected, true);
            app.DiscardRecommendationListMenu.Text = 'Discard Recommendation List';
            app.AutoSaveRecommendationListMenu = uimenu(app.OptionsMenu);
            app.AutoSaveRecommendationListMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSaveRecommendationListMenuSelected, true);
            app.AutoSaveRecommendationListMenu.Checked = 'on';
            app.AutoSaveRecommendationListMenu.Text = 'Auto Save Recommendation List';
            app.LastAutoSaveMenu = uimenu(app.OptionsMenu);
            app.LastAutoSaveMenu.Enable = 'off';
            app.LastAutoSaveMenu.Text = 'Last Auto Save @  ';
            app.LanguagesMenu = uimenu(app.OptionsMenu);
            app.LanguagesMenu.Separator = 'on';
            app.LanguagesMenu.Text = 'Languages';
            app.EnglishMenu = uimenu(app.LanguagesMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Checked = 'on';
            app.EnglishMenu.Text = 'English';
            app.ChineseMenu = uimenu(app.LanguagesMenu);
            app.ChineseMenu.MenuSelectedFcn = createCallbackFcn(app, @ChineseMenuSelected, true);
            app.ChineseMenu.Text = '简体中文';
            app.JapaneseMenu = uimenu(app.LanguagesMenu);
            app.JapaneseMenu.MenuSelectedFcn = createCallbackFcn(app, @JapaneseMenuSelected, true);
            app.JapaneseMenu.Text = '日本語';
            app.RussianMenu = uimenu(app.LanguagesMenu);
            app.RussianMenu.MenuSelectedFcn = createCallbackFcn(app, @RussianMenuSelected, true);
            app.RussianMenu.Text = 'русск';
            app.ScriptMenu = uimenu(app.UIFigure);
            app.ScriptMenu.Text = 'Script';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.DevelopersMenu = uimenu(app.HelpMenu);
            app.DevelopersMenu.Text = 'Developers';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RealTimeMonitorTab = uitab(app.TabGroup);
            app.RealTimeMonitorTab.Title = 'Real Time Monitor';
            app.UIAxes_1 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_2 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_3 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_4 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_5 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_6 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_7 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_8 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_9 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_10 = uiaxes(app.RealTimeMonitorTab);
            app.Lamp_1 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_2 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_3 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_4 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_5 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_6 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_7 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_8 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_9 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_10 = uilamp(app.RealTimeMonitorTab);
            app.NameLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_1.Text = '--';
            app.NameLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_2.Text = '--';
            app.NameLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_3.Text = '--';
            app.NameLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_4.Text = '--';
            app.NameLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_5.Text = '--';
            app.NameLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_6.Text = '--';
            app.NameLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_7.Text = '--';
            app.NameLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_8.Text = '--';
            app.NameLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_9.Text = '--';
            app.NameLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_10.Text = '--';
            app.DateLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_1.Text = '--';
            app.DateLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_2.Text = '--';
            app.DateLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_3.Text = '--';
            app.DateLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_4.Text = '--';
            app.DateLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_5.Text = '--';
            app.DateLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_6.Text = '--';
            app.DateLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_7.Text = '--';
            app.DateLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_8.Text = '--';
            app.DateLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_9.Text = '--';
            app.DateLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_10.Text = '--';
            app.CodeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_1.Text = '--';
            app.CodeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_2.Text = '--';
            app.CodeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_3.Text = '--';
            app.CodeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_4.Text = '--';
            app.CodeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_5.Text = '--';
            app.CodeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_6.Text = '--';
            app.CodeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_7.Text = '--';
            app.CodeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_8.Text = '--';
            app.CodeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_9.Text = '--';
            app.CodeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_10.Text = '--';
            app.Panel = uipanel(app.RealTimeMonitorTab);
            app.RealTimeButton = uibutton(app.Panel, 'state');
            app.RealTimeButton.ValueChangedFcn = createCallbackFcn(app, @RealTimeButtonValueChanged, true);
            app.RealTimeButton.Text = 'Real Time';
            app.PredictionButton = uibutton(app.Panel, 'push');
            app.PredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionButtonPushed, true);
            app.PredictionButton.Text = 'Prediction';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.RePredCheckBox = uicheckbox(app.Panel);
            app.RePredCheckBox.Text = 'RePred';
            app.Progressbar_2 = uilabel(app.Panel);
            app.Progressbar_2.Text = '';
            app.Progressbar = uilabel(app.Panel);
            app.Progressbar.Text = '';
            app.PredCodeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_1.Text = '--';
            app.PredCodeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_2.Text = '--';
            app.PredCodeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_3.Text = '--';
            app.PredCodeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_4.Text = '--';
            app.PredCodeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_5.Text = '--';
            app.PredCodeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_6.Text = '--';
            app.PredCodeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_7.Text = '--';
            app.PredCodeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_8.Text = '--';
            app.PredCodeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_9.Text = '--';
            app.PredCodeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_10.Text = '--';
            app.RTimeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_1.Text = '--';
            app.RTimeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_2.Text = '--';
            app.RTimeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_3.Text = '--';
            app.RTimeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_4.Text = '--';
            app.RTimeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_5.Text = '--';
            app.RTimeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_6.Text = '--';
            app.RTimeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_7.Text = '--';
            app.RTimeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_8.Text = '--';
            app.RTimeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_9.Text = '--';
            app.RTimeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_10.Text = '--';
            app.RTLamp_1 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_2 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_3 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_4 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_5 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_6 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_7 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_8 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_9 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_10 = uilamp(app.RealTimeMonitorTab);
            app.StockListTab = uitab(app.TabGroup);
            app.StockListTab.Title = 'Stock List';
            app.StockList = uilistbox(app.StockListTab);
            app.StockList.Items = {};
            app.StockList.ValueChangedFcn = createCallbackFcn(app, @StockListValueChanged, true);
            app.StockList.Value = {};
            app.AddCSButton = uibutton(app.StockListTab, 'push');
            app.AddCSButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSButtonPushed, true);
            app.AddCSButton.Text = '>>>';
            app.CSList = uilistbox(app.StockListTab);
            app.CSList.Items = {};
            app.CSList.ValueChangedFcn = createCallbackFcn(app, @CSListValueChanged, true);
            app.CSList.Value = {};
            app.RemoveCSButton = uibutton(app.StockListTab, 'push');
            app.RemoveCSButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCSButtonPushed, true);
            app.RemoveCSButton.Text = '<<<';
            app.KMSList = uilistbox(app.StockListTab);
            app.KMSList.Items = {};
            app.KMSList.ValueChangedFcn = createCallbackFcn(app, @KMSListValueChanged, true);
            app.KMSList.Value = {};
            app.AddKMSButton = uibutton(app.StockListTab, 'push');
            app.AddKMSButton.ButtonPushedFcn = createCallbackFcn(app, @AddKMSButtonPushed, true);
            app.AddKMSButton.Text = '>>>';
            app.RemoveKMSButton = uibutton(app.StockListTab, 'push');
            app.RemoveKMSButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveKMSButtonPushed, true);
            app.RemoveKMSButton.Text = '<<<';
            app.SearchField = uieditfield(app.StockListTab, 'text');
            app.SearchButton = uibutton(app.StockListTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.CSLabel = uilabel(app.StockListTab);
            app.CSLabel.Text = 'Candidates Stocks';
            app.KMSLabel = uilabel(app.StockListTab);
            app.KMSLabel.Text = 'Key Monitor Stocks';
            app.KlineShower = uiaxes(app.StockListTab);
            app.StartButton = uibutton(app.StockListTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.InPoolButton = uibutton(app.StockListTab, 'push');
            app.InPoolButton.ButtonPushedFcn = createCallbackFcn(app, @InPoolButtonPushed, true);
            app.InPoolButton.Text = 'InPool';
            app.ALLButton = uibutton(app.StockListTab, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.ClearKMSButton = uibutton(app.StockListTab, 'push');
            app.ClearKMSButton.ButtonPushedFcn = createCallbackFcn(app, @ClearKMSButtonPushed, true);
            app.ClearKMSButton.Text = 'Clear';
            app.ClearCSButton = uibutton(app.StockListTab, 'push');
            app.ClearCSButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCSButtonPushed, true);
            app.ClearCSButton.Text = 'Clear';
            app.ShuffleButton = uibutton(app.StockListTab, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.RecommendationSystemTab = uitab(app.TabGroup);
            app.RecommendationSystemTab.Title = 'Recommendation System';
            app.StockPoolList = uilistbox(app.RecommendationSystemTab);
            app.StockPoolList.Items = {};
            app.StockPoolList.Value = {};
            app.StockPoolLabel = uilabel(app.RecommendationSystemTab);
            app.StockPoolLabel.Text = 'Stock Pool';
            app.Label_2 = uilabel(app.RecommendationSystemTab);
            app.Label_2.Text = '';
            app.ProgressLabel2 = uilabel(app.RecommendationSystemTab);
            app.ProgressLabel2.Text = '%';
            app.Progressbar2 = uilabel(app.RecommendationSystemTab);
            app.Progressbar2.Text = '';
            app.RunButton = uibutton(app.RecommendationSystemTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run ▶';
            app.ResetButtonRecommender = uibutton(app.RecommendationSystemTab, 'push');
            app.ResetButtonRecommender.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonRecommenderPushed, true);
            app.ResetButtonRecommender.Text = 'Reset';
            app.RecommandTableGroup = uitabgroup(app.RecommendationSystemTab);
            app.SuggestionTab = uitab(app.RecommandTableGroup);
            app.SuggestionTab.Title = 'Suggestion';
            app.SuggestionTable = uitable(app.SuggestionTab);
            app.SuggestionTable.ColumnName = {'S'; 'ID'; 'Name'; 'Code'; 'Price'; 'Pred Low Price'; 'Pred High Price'; 'Amplitude (%)'; 'Pred Date'};
            app.SuggestionTable.RowName = {};
            app.SuggestionTable.ColumnSortable = [true true true true true true false true true];
            app.SuggestionTable.ColumnEditable = [false false false false false true true false false];
            app.SuggestionTable.CellSelectionCallback = createCallbackFcn(app, @SuggestionTableCellSelection, true);
            app.NLabel = uilabel(app.SuggestionTab);
            app.NLabel.Text = 'N = ';
            app.SugNLabel = uilabel(app.SuggestionTab);
            app.SugNLabel.Text = '';
            app.DeprecationTab = uitab(app.RecommandTableGroup);
            app.DeprecationTab.Title = 'Deprecation';
            app.DeprecationTable = uitable(app.DeprecationTab);
            app.DeprecationTable.ColumnName = {'S'; 'ID'; 'Name'; 'Code'; 'Price'; 'Pred Low Price'; 'Pred High Price'; 'Amplitude (%)'; 'Pred Date'};
            app.DeprecationTable.RowName = {};
            app.DeprecationTable.ColumnSortable = [true false false false false false false false false];
            app.DeprecationTable.ColumnEditable = [false false false false false true true false false];
            app.NLabel_2 = uilabel(app.DeprecationTab);
            app.NLabel_2.Text = 'N = ';
            app.DepNLabel = uilabel(app.DeprecationTab);
            app.DepNLabel.Text = '';
            app.NeutralTab = uitab(app.RecommandTableGroup);
            app.NeutralTab.Title = 'Neutral';
            app.NeutralTable = uitable(app.NeutralTab);
            app.NeutralTable.ColumnName = {'S'; 'ID'; 'Name'; 'Code'; 'Price'; 'Pred Low Price'; 'Pred High Price'; 'Amplitude (%)'; 'Pred Date'};
            app.NeutralTable.RowName = {};
            app.NeutralTable.ColumnSortable = [true false false false false false false false false];
            app.NeutralTable.ColumnEditable = [false false false false false true true false false];
            app.NLabel_3 = uilabel(app.NeutralTab);
            app.NLabel_3.Text = 'N = ';
            app.NeuNLabel = uilabel(app.NeutralTab);
            app.NeuNLabel.Text = '';
            app.ControlRandomTab = uitab(app.RecommandTableGroup);
            app.ControlRandomTab.Title = 'Control (Random)';
            app.ControlTable = uitable(app.ControlRandomTab);
            app.ControlTable.ColumnName = {'S'; 'ID'; 'Name'; 'Code'; 'Price'; 'Pred  Low Price'; 'Pred High Price'; 'Amplitude (%)'; 'Pred Date'};
            app.ControlTable.RowName = {};
            app.ControlTable.ColumnSortable = [true true true true true false false true true];
            app.ControlTable.ColumnEditable = [false false false false false true true false false];
            app.NLabel_4 = uilabel(app.ControlRandomTab);
            app.NLabel_4.Text = 'N = ';
            app.ConNLabel = uilabel(app.ControlRandomTab);
            app.ConNLabel.Text = '';
            app.MaxLabel = uilabel(app.RecommendationSystemTab);
            app.MaxLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.RecommendationSystemTab, 'numeric');
            app.MaxEditField.RoundFractionalValues = 'on';
            app.MaxEditField.Value = 20;
            app.AddRecommandTableButton = uibutton(app.RecommendationSystemTab, 'push');
            app.AddRecommandTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddRecommandTableButtonPushed, true);
            app.AddRecommandTableButton.Text = '>>>';
            app.SaveDataButton = uibutton(app.RecommendationSystemTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.RecommendationSystemTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TradeSimulationSystemTab = uitab(app.TabGroup);
            app.TradeSimulationSystemTab.Title = 'Trade Simulation System';
            app.InOutTabGroup = uitabgroup(app.TradeSimulationSystemTab);
            app.PurchaseInTab = uitab(app.InOutTabGroup);
            app.PurchaseInTab.Title = 'Purchase In';
            app.AddFromRSButton = uibutton(app.PurchaseInTab, 'push');
            app.AddFromRSButton.ButtonPushedFcn = createCallbackFcn(app, @AddFromRSButtonPushed, true);
            app.AddFromRSButton.Text = 'Add From RS';
            app.ClearButton = uibutton(app.PurchaseInTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PurchaseButton = uibutton(app.PurchaseInTab, 'push');
            app.PurchaseButton.ButtonPushedFcn = createCallbackFcn(app, @PurchaseButtonPushed, true);
            app.PurchaseButton.Text = 'Purchase';
            app.BuyTable = uitable(app.PurchaseInTab);
            app.BuyTable.ColumnName = {'ID'; 'Name'; 'Code'; 'Price'; 'Pred Low Price'; 'Pred High Price'; 'Amplitude'; 'Pred Date'; 'Bill'};
            app.BuyTable.RowName = {};
            app.BuyTable.ColumnEditable = [false false false false false false false false true];
            app.AddCandidatesButton = uibutton(app.PurchaseInTab, 'push');
            app.AddCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @AddCandidatesButtonPushed, true);
            app.AddCandidatesButton.Text = 'Add Candidates';
            app.SellOutTab = uitab(app.InOutTabGroup);
            app.SellOutTab.Title = 'Sell Out';
            app.SellTable = uitable(app.SellOutTab);
            app.SellTable.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Buy Price'; 'Amplitude (%)'; 'Bill'; 'Sell Bills'; 'S'};
            app.SellTable.RowName = {};
            app.SellTable.ColumnEditable = [false false false false false false false false true false];
            app.SellTable.CellSelectionCallback = createCallbackFcn(app, @SellTableCellSelection, true);
            app.SellButton = uibutton(app.SellOutTab, 'push');
            app.SellButton.ButtonPushedFcn = createCallbackFcn(app, @SellButtonPushed, true);
            app.SellButton.Text = 'Sell';
            app.AutoSellButton = uibutton(app.SellOutTab, 'state');
            app.AutoSellButton.Text = 'Auto Sell';
            app.ThresholdEditFieldLabel = uilabel(app.SellOutTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SellOutTab, 'numeric');
            app.Label_3 = uilabel(app.SellOutTab);
            app.Label_3.Text = '%';
            app.SumTabGroup = uitabgroup(app.TradeSimulationSystemTab);
            app.SumTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SumTabGroupSelectionChanged, true);
            app.SuggestionTabSum = uitab(app.SumTabGroup);
            app.SuggestionTabSum.Title = 'Suggestion';
            app.SumTableSuggestion = uitable(app.SuggestionTabSum);
            app.SumTableSuggestion.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Low Price'; 'High Price'; 'Buy Price'; 'Buy Date'; 'Pred Low Price'; 'Pred High Price'; 'Pred Date'; 'Bill'; 'Income'};
            app.SumTableSuggestion.RowName = {};
            app.Label99 = uilabel(app.SuggestionTabSum);
            app.Label99.Text = 'Increase :';
            app.IncreaseLabelSuggestion = uilabel(app.SuggestionTabSum);
            app.IncreaseLabelSuggestion.Text = '-- / --';
            app.IncreaseRateLabelSuggestion = uilabel(app.SuggestionTabSum);
            app.IncreaseRateLabelSuggestion.Text = '-- %';
            app.TotalSumEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.TotalSumEditFieldLabel.Text = 'Total Sum';
            app.TotalSumEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.TotalSumEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.TotalSumEditFieldSuggestion.Editable = 'off';
            app.TotalSumEditFieldSuggestion.Value = 1000000;
            app.AccountSumEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.AccountSumEditFieldLabel.Text = 'Account Sum';
            app.AccountSumEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.AccountSumEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.AccountSumEditFieldSuggestion.Editable = 'off';
            app.AccountSumEditFieldSuggestion.Value = 1000000;
            app.InvestSumEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.InvestSumEditFieldLabel.Text = 'Invest Sum';
            app.InvestSumEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.InvestSumEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.InvestSumEditFieldSuggestion.Editable = 'off';
            app.ReturnSumEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.ReturnSumEditFieldLabel.Text = 'Return Sum';
            app.ReturnSumEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.ReturnSumEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.ReturnSumEditFieldSuggestion.Editable = 'off';
            app.TotalIncomeEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.TotalIncomeEditFieldLabel.Text = 'Total Income';
            app.TotalIncomeEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.TotalIncomeEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.TotalIncomeEditFieldSuggestion.Editable = 'off';
            app.InitialSumEditFieldLabel = uilabel(app.SuggestionTabSum);
            app.InitialSumEditFieldLabel.Text = 'Initial Sum';
            app.InitialSumEditFieldSuggestion = uieditfield(app.SuggestionTabSum, 'numeric');
            app.InitialSumEditFieldSuggestion.ValueDisplayFormat = '%.2f';
            app.InitialSumEditFieldSuggestion.Editable = 'off';
            app.GlobalPvalue_4 = uilabel(app.SuggestionTabSum);
            app.GlobalPvalue_4.Text = '--';
            app.vsControlPValue_4 = uilabel(app.SuggestionTabSum);
            app.vsControlPValue_4.Text = '--';
            app.GloballyLabel_6 = uilabel(app.SuggestionTabSum);
            app.GloballyLabel_6.Text = 'Globally ';
            app.vsControlLabel_6 = uilabel(app.SuggestionTabSum);
            app.vsControlLabel_6.Text = 'vs Control';
            app.DeprecationTabSum = uitab(app.SumTabGroup);
            app.DeprecationTabSum.Title = 'Deprecation';
            app.SumTableDeprecation = uitable(app.DeprecationTabSum);
            app.SumTableDeprecation.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Low Price'; 'High Price'; 'Buy Price'; 'Buy Date'; 'Pred Low Price'; 'Pred High Price'; 'Pred Date'; 'Bill'; 'Income'};
            app.SumTableDeprecation.RowName = {};
            app.Label99_2 = uilabel(app.DeprecationTabSum);
            app.Label99_2.Text = 'Increase : ';
            app.IncreaseLabelDeprecation = uilabel(app.DeprecationTabSum);
            app.IncreaseLabelDeprecation.Text = '-- / --';
            app.IncreaseRateLabelDeprecation = uilabel(app.DeprecationTabSum);
            app.IncreaseRateLabelDeprecation.Text = '-- %';
            app.AccountSumEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.AccountSumEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.AccountSumEditFieldDeprecation.Editable = 'off';
            app.AccountSumEditFieldDeprecation.Value = 1000000;
            app.TotalSumEditField_2Label = uilabel(app.DeprecationTabSum);
            app.TotalSumEditField_2Label.Text = 'Total Sum';
            app.TotalSumEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.TotalSumEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.TotalSumEditFieldDeprecation.Editable = 'off';
            app.TotalSumEditFieldDeprecation.Value = 1000000;
            app.InvestSumEditField_2Label = uilabel(app.DeprecationTabSum);
            app.InvestSumEditField_2Label.Text = 'Invest Sum';
            app.InvestSumEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.InvestSumEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.InvestSumEditFieldDeprecation.Editable = 'off';
            app.ReturnSumEditField_2Label = uilabel(app.DeprecationTabSum);
            app.ReturnSumEditField_2Label.Text = 'Return Sum';
            app.ReturnSumEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.ReturnSumEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.ReturnSumEditFieldDeprecation.Editable = 'off';
            app.TotalIncomeEditField_2Label = uilabel(app.DeprecationTabSum);
            app.TotalIncomeEditField_2Label.Text = 'Total Income';
            app.TotalIncomeEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.TotalIncomeEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.TotalIncomeEditFieldDeprecation.Editable = 'off';
            app.InitialSumEditField_2Label = uilabel(app.DeprecationTabSum);
            app.InitialSumEditField_2Label.Text = 'Initial Sum';
            app.InitialSumEditFieldDeprecation = uieditfield(app.DeprecationTabSum, 'numeric');
            app.InitialSumEditFieldDeprecation.ValueDisplayFormat = '%.2f';
            app.InitialSumEditFieldDeprecation.Editable = 'off';
            app.AccountSumEditFieldLabel_2 = uilabel(app.DeprecationTabSum);
            app.AccountSumEditFieldLabel_2.Text = 'Account Sum';
            app.GlobalPvalue_3 = uilabel(app.DeprecationTabSum);
            app.GlobalPvalue_3.Text = '--';
            app.vsControlPValue_3 = uilabel(app.DeprecationTabSum);
            app.vsControlPValue_3.Text = '--';
            app.GloballyLabel_5 = uilabel(app.DeprecationTabSum);
            app.GloballyLabel_5.Text = 'Globally ';
            app.vsControlLabel_5 = uilabel(app.DeprecationTabSum);
            app.vsControlLabel_5.Text = 'vs Control';
            app.NeutralTabSum = uitab(app.SumTabGroup);
            app.NeutralTabSum.Title = 'Neutral';
            app.SumTableNeutral = uitable(app.NeutralTabSum);
            app.SumTableNeutral.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Low Price'; 'High Price'; 'Buy Price'; 'Buy Date'; 'Pred Low Price'; 'Pred High Price'; 'Pred Date'; 'Bill'; 'Income'};
            app.SumTableNeutral.RowName = {};
            app.Label99_4 = uilabel(app.NeutralTabSum);
            app.Label99_4.Text = 'Increase : ';
            app.IncreaseLabelNeutral = uilabel(app.NeutralTabSum);
            app.IncreaseLabelNeutral.Text = '-- / --';
            app.IncreaseRateLabelNeutral = uilabel(app.NeutralTabSum);
            app.IncreaseRateLabelNeutral.Text = '-- %';
            app.AccountSumEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.AccountSumEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.AccountSumEditFieldNeutral.Editable = 'off';
            app.AccountSumEditFieldNeutral.Value = 1000000;
            app.TotalSumEditField_4Label = uilabel(app.NeutralTabSum);
            app.TotalSumEditField_4Label.Text = 'Total Sum';
            app.TotalSumEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.TotalSumEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.TotalSumEditFieldNeutral.Editable = 'off';
            app.TotalSumEditFieldNeutral.Value = 1000000;
            app.InvestSumEditField_4Label = uilabel(app.NeutralTabSum);
            app.InvestSumEditField_4Label.Text = 'Invest Sum';
            app.InvestSumEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.InvestSumEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.InvestSumEditFieldNeutral.Editable = 'off';
            app.ReturnSumEditField_4Label = uilabel(app.NeutralTabSum);
            app.ReturnSumEditField_4Label.Text = 'Return Sum';
            app.ReturnSumEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.ReturnSumEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.ReturnSumEditFieldNeutral.Editable = 'off';
            app.TotalIncomeEditField_4Label = uilabel(app.NeutralTabSum);
            app.TotalIncomeEditField_4Label.Text = 'Total Income';
            app.TotalIncomeEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.TotalIncomeEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.TotalIncomeEditFieldNeutral.Editable = 'off';
            app.InitialSumEditField_4Label = uilabel(app.NeutralTabSum);
            app.InitialSumEditField_4Label.Text = 'Initial Sum';
            app.InitialSumEditFieldNeutral = uieditfield(app.NeutralTabSum, 'numeric');
            app.InitialSumEditFieldNeutral.ValueDisplayFormat = '%.2f';
            app.InitialSumEditFieldNeutral.Editable = 'off';
            app.AccountSumEditFieldLabel_4 = uilabel(app.NeutralTabSum);
            app.AccountSumEditFieldLabel_4.Text = 'Account Sum';
            app.GloballyLabel_2 = uilabel(app.NeutralTabSum);
            app.GloballyLabel_2.Text = 'Globally ';
            app.vsControlLabel_2 = uilabel(app.NeutralTabSum);
            app.vsControlLabel_2.Text = 'vs Control';
            app.GlobalPvalue_2 = uilabel(app.NeutralTabSum);
            app.GlobalPvalue_2.Text = '--';
            app.vsControlPValue_2 = uilabel(app.NeutralTabSum);
            app.vsControlPValue_2.Text = '--';
            app.ControlRandomTabSum = uitab(app.SumTabGroup);
            app.ControlRandomTabSum.Title = 'Control (Random)';
            app.SumTableControlRandom = uitable(app.ControlRandomTabSum);
            app.SumTableControlRandom.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Low Price'; 'High Price'; 'Buy Price'; 'Buy Date'; 'Pred Low Price'; 'Pred High Price'; 'Pred Date'; 'Bill'; 'Income'};
            app.SumTableControlRandom.RowName = {};
            app.Label99_3 = uilabel(app.ControlRandomTabSum);
            app.Label99_3.Text = 'Increase : ';
            app.IncreaseLabelControlRandom = uilabel(app.ControlRandomTabSum);
            app.IncreaseLabelControlRandom.Text = '-- / --';
            app.IncreaseRateLabelControlRandom = uilabel(app.ControlRandomTabSum);
            app.IncreaseRateLabelControlRandom.Text = '-- %';
            app.AccountSumEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.AccountSumEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.AccountSumEditFieldControlRandom.Editable = 'off';
            app.AccountSumEditFieldControlRandom.Value = 1000000;
            app.TotalSumEditField_3Label = uilabel(app.ControlRandomTabSum);
            app.TotalSumEditField_3Label.Text = 'Total Sum';
            app.TotalSumEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.TotalSumEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.TotalSumEditFieldControlRandom.Editable = 'off';
            app.TotalSumEditFieldControlRandom.Value = 1000000;
            app.InvestSumEditField_3Label = uilabel(app.ControlRandomTabSum);
            app.InvestSumEditField_3Label.Text = 'Invest Sum';
            app.InvestSumEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.InvestSumEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.InvestSumEditFieldControlRandom.Editable = 'off';
            app.ReturnSumEditField_3Label = uilabel(app.ControlRandomTabSum);
            app.ReturnSumEditField_3Label.Text = 'Return Sum';
            app.ReturnSumEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.ReturnSumEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.ReturnSumEditFieldControlRandom.Editable = 'off';
            app.TotalIncomeEditField_3Label = uilabel(app.ControlRandomTabSum);
            app.TotalIncomeEditField_3Label.Text = 'Total Income';
            app.TotalIncomeEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.TotalIncomeEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.TotalIncomeEditFieldControlRandom.Editable = 'off';
            app.InitialSumEditField_3Label = uilabel(app.ControlRandomTabSum);
            app.InitialSumEditField_3Label.Text = 'Initial Sum';
            app.InitialSumEditFieldControlRandom = uieditfield(app.ControlRandomTabSum, 'numeric');
            app.InitialSumEditFieldControlRandom.ValueDisplayFormat = '%.2f';
            app.InitialSumEditFieldControlRandom.Editable = 'off';
            app.AccountSumEditFieldLabel_3 = uilabel(app.ControlRandomTabSum);
            app.AccountSumEditFieldLabel_3.Text = 'Account Sum';
            app.GloballyLabel = uilabel(app.ControlRandomTabSum);
            app.GloballyLabel.Text = 'Globally ';
            app.vsControlLabel = uilabel(app.ControlRandomTabSum);
            app.vsControlLabel.Text = 'vs Control';
            app.GlobalPvalue = uilabel(app.ControlRandomTabSum);
            app.GlobalPvalue.Text = '--';
            app.vsControlPValue = uilabel(app.ControlRandomTabSum);
            app.vsControlPValue.Text = '--';
            app.StatisticalAnalysisTab = uitab(app.TabGroup);
            app.StatisticalAnalysisTab.Title = 'Statistical Analysis';
            app.AmpAxes = uiaxes(app.StatisticalAnalysisTab);
            title(app.AmpAxes, 'Amplitude')
            xlabel(app.AmpAxes, 'Group Variables')
            ylabel(app.AmpAxes, 'Amplitude of Increasement')
            app.RatioAxes = uiaxes(app.StatisticalAnalysisTab);
            title(app.RatioAxes, 'Proportion')
            xlabel(app.RatioAxes, 'Group Variables')
            ylabel(app.RatioAxes, 'Proportion of Increasement')
            app.AmpStaArea = uitextarea(app.StatisticalAnalysisTab);
            app.ProStaArea = uitextarea(app.StatisticalAnalysisTab);
            app.HitStaAxes = uiaxes(app.StatisticalAnalysisTab);
            title(app.HitStaAxes, 'Hitness')
            xlabel(app.HitStaAxes, 'Group Variables')
            ylabel(app.HitStaAxes, 'Hitness Ratio')
            app.EarnStaAxes = uiaxes(app.StatisticalAnalysisTab);
            title(app.EarnStaAxes, 'Earn')
            xlabel(app.EarnStaAxes, 'Group Variables')
            ylabel(app.EarnStaAxes, 'Earn Ratio')
            app.HitStaArea = uitextarea(app.StatisticalAnalysisTab);
            app.EarnStaArea = uitextarea(app.StatisticalAnalysisTab);
            app.MonitorPanel = uipanel(app.UIFigure);
            app.TradeMonitorSwitchLabel = uilabel(app.MonitorPanel);
            app.TradeMonitorSwitchLabel.Text = 'Trade Monitor';
            app.TradeMonitorSwitch = uiswitch(app.MonitorPanel, 'slider');
            app.TradeMonitorSwitch.ValueChangedFcn = createCallbackFcn(app, @TradeMonitorSwitchValueChanged, true);
            app.ProgressLabel3 = uilabel(app.MonitorPanel);
            app.ProgressLabel3.Text = '%';
            app.PendingLabel = uilabel(app.MonitorPanel);
            app.PendingLabel.Text = 'Pending ：';
            app.DesginedandProgrammedbyZhangHengLabel = uilabel(app.UIFigure);
            app.DesginedandProgrammedbyZhangHengLabel.Text = 'Desgined and Programmed by Zhang Heng';
            app.ResetButtonMonitor = uibutton(app.UIFigure, 'push');
            app.ResetButtonMonitor.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonMonitorPushed, true);
            app.ResetButtonMonitor.Text = 'Reset';
            app.UnLockButton = uibutton(app.UIFigure, 'state');
            app.UnLockButton.Text = 'UnLock';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Progressbar3 = uilabel(app.UIFigure);
            app.Progressbar3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realtimeer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end