classdef realtimeerDEMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        RealTimeMonitorTab         matlab.ui.container.Tab
        UIAxes_1                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_7                   matlab.ui.control.UIAxes
        UIAxes_8                   matlab.ui.control.UIAxes
        UIAxes_9                   matlab.ui.control.UIAxes
        UIAxes_10                  matlab.ui.control.UIAxes
        Lamp_1                     matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_4                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        Lamp_6                     matlab.ui.control.Lamp
        Lamp_7                     matlab.ui.control.Lamp
        Lamp_8                     matlab.ui.control.Lamp
        Lamp_9                     matlab.ui.control.Lamp
        Lamp_10                    matlab.ui.control.Lamp
        NameLabel_1                matlab.ui.control.Label
        NameLabel_2                matlab.ui.control.Label
        NameLabel_3                matlab.ui.control.Label
        NameLabel_4                matlab.ui.control.Label
        NameLabel_5                matlab.ui.control.Label
        NameLabel_6                matlab.ui.control.Label
        NameLabel_7                matlab.ui.control.Label
        NameLabel_8                matlab.ui.control.Label
        NameLabel_9                matlab.ui.control.Label
        NameLabel_10               matlab.ui.control.Label
        DateLabel_1                matlab.ui.control.Label
        DateLabel_2                matlab.ui.control.Label
        DateLabel_3                matlab.ui.control.Label
        DateLabel_4                matlab.ui.control.Label
        DateLabel_5                matlab.ui.control.Label
        DateLabel_6                matlab.ui.control.Label
        DateLabel_7                matlab.ui.control.Label
        DateLabel_8                matlab.ui.control.Label
        DateLabel_9                matlab.ui.control.Label
        DateLabel_10               matlab.ui.control.Label
        CodeLabel_1                matlab.ui.control.Label
        CodeLabel_2                matlab.ui.control.Label
        CodeLabel_3                matlab.ui.control.Label
        CodeLabel_4                matlab.ui.control.Label
        CodeLabel_5                matlab.ui.control.Label
        CodeLabel_6                matlab.ui.control.Label
        CodeLabel_7                matlab.ui.control.Label
        CodeLabel_8                matlab.ui.control.Label
        CodeLabel_9                matlab.ui.control.Label
        CodeLabel_10               matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        RealTimeButton             matlab.ui.control.StateButton
        PredictionButton           matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        RePredCheckBox             matlab.ui.control.CheckBox
        Progressbar_2              matlab.ui.control.Label
        Progressbar                matlab.ui.control.Label
        PredCodeLabel_1            matlab.ui.control.Label
        PredCodeLabel_2            matlab.ui.control.Label
        PredCodeLabel_3            matlab.ui.control.Label
        PredCodeLabel_4            matlab.ui.control.Label
        PredCodeLabel_5            matlab.ui.control.Label
        PredCodeLabel_6            matlab.ui.control.Label
        PredCodeLabel_7            matlab.ui.control.Label
        PredCodeLabel_8            matlab.ui.control.Label
        PredCodeLabel_9            matlab.ui.control.Label
        PredCodeLabel_10           matlab.ui.control.Label
        RTimeLabel_1               matlab.ui.control.Label
        RTimeLabel_2               matlab.ui.control.Label
        RTimeLabel_3               matlab.ui.control.Label
        RTimeLabel_4               matlab.ui.control.Label
        RTimeLabel_5               matlab.ui.control.Label
        RTimeLabel_6               matlab.ui.control.Label
        RTimeLabel_7               matlab.ui.control.Label
        RTimeLabel_8               matlab.ui.control.Label
        RTimeLabel_9               matlab.ui.control.Label
        RTimeLabel_10              matlab.ui.control.Label
        RTLamp_1                   matlab.ui.control.Lamp
        RTLamp_2                   matlab.ui.control.Lamp
        RTLamp_3                   matlab.ui.control.Lamp
        RTLamp_4                   matlab.ui.control.Lamp
        RTLamp_5                   matlab.ui.control.Lamp
        RTLamp_6                   matlab.ui.control.Lamp
        RTLamp_7                   matlab.ui.control.Lamp
        RTLamp_8                   matlab.ui.control.Lamp
        RTLamp_9                   matlab.ui.control.Lamp
        RTLamp_10                  matlab.ui.control.Lamp
        StockListTab               matlab.ui.container.Tab
        StockList                  matlab.ui.control.ListBox
        AddCSButton                matlab.ui.control.Button
        CSList                     matlab.ui.control.ListBox
        RemoveCSButton             matlab.ui.control.Button
        KMSList                    matlab.ui.control.ListBox
        AddKMSButton               matlab.ui.control.Button
        RemoveKMSButton            matlab.ui.control.Button
        SearchField                matlab.ui.control.EditField
        SearchButton               matlab.ui.control.Button
        CSLabel                    matlab.ui.control.Label
        KMSLabel                   matlab.ui.control.Label
        RealTimeGIF                matlab.ui.control.UIAxes
        DayKLineGIF                matlab.ui.control.UIAxes
        StartButton                matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ALLButton                  matlab.ui.control.Button
        ClearKMSButton             matlab.ui.control.Button
        ClearCSButton              matlab.ui.control.Button
        RecommenderSystemTab       matlab.ui.container.Tab
        StockPoolList              matlab.ui.control.ListBox
        UITable                    matlab.ui.control.Table
        StockPoolLabel             matlab.ui.control.Label
        SuggestionLabel            matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        ProgressLabel2             matlab.ui.control.Label
        Progressbar2               matlab.ui.control.Label
        RunButton                  matlab.ui.control.StateButton
        ResetButton                matlab.ui.control.Button
        TradeSimulationSystemTab   matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        InTab                      matlab.ui.container.Tab
        AddSuggestionsButton       matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        PurchaseButton             matlab.ui.control.Button
        BuyTable                   matlab.ui.control.Table
        OutTab                     matlab.ui.container.Tab
        SellTable                  matlab.ui.control.Table
        SellButton                 matlab.ui.control.Button
        AutoSellButton             matlab.ui.control.StateButton
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        SumTable                   matlab.ui.control.Table
        TotalSumEditFieldLabel     matlab.ui.control.Label
        TotalSumEditField          matlab.ui.control.NumericEditField
        AccountSumEditFieldLabel   matlab.ui.control.Label
        AccountSumEditField        matlab.ui.control.NumericEditField
        InvestSumEditFieldLabel    matlab.ui.control.Label
        InvestSumEditField         matlab.ui.control.NumericEditField
        ReturnSumEditFieldLabel    matlab.ui.control.Label
        ReturnSumEditField         matlab.ui.control.NumericEditField
        TotalIncomeEditFieldLabel  matlab.ui.control.Label
        TotalIncomeEditField       matlab.ui.control.NumericEditField
        InitialSumEditFieldLabel   matlab.ui.control.Label
        InitialSumEditField        matlab.ui.control.NumericEditField
        Label99                    matlab.ui.control.Label
        IncreaseLabel              matlab.ui.control.Label
        IncreaseRateLabel          matlab.ui.control.Label
        Label99_2                  matlab.ui.control.Label
        TradeMonitorSwitchLabel    matlab.ui.control.Label
        TradeMonitorSwitch         matlab.ui.control.Switch
        ResetButton_2              matlab.ui.control.Button
        UnLockButton               matlab.ui.control.StateButton
    end


    properties (Access = private)
        hand % Description
        sum % Description
    end

    methods (Access = private)

        function setProgressbar(app,val)
            % 设置进度条进度值 
            progress = ceil(653 * val);
            position = get(app.Progressbar,'position');
            position(3) = progress;
            set(app.Progressbar,'position',position);
            set(app.Label,'text',[num2str(round(val*100)) ' %']);
            drawnow();
        end
        
        function callback(app,traininfo)
            app.setProgressbar(traininfo.Epoch / 500);
        end
        
        function initshow(app,index)
            show = app.getshow(index);
            hold(show.ax,'on');
            show.line1 = line(show.ax,[0 2],[10 10],'linewidth',2);
            show.line2 = line(show.ax,[0 2],[20 20],'linewidth',2);
            show.mark = scatter(show.ax,1,15,'r','linewidth',2);
            show.rect = rectangle(show.ax,'position',[0.5 10 1 10]);
            set(show.ax,'linewidth',1,'xtick',[],'box','on');
            hold(show.ax,'off');
            % 句柄只能访问不能修改
            app.setshow(show,index);
        end
        
        function res = getshow(app,index)
            switch index
                case 1
                    app.hand.show.show1.ax = app.UIAxes_1;
                    app.hand.show.show1.nameLabel = app.NameLabel_1;
                    app.hand.show.show1.codeLabel = app.CodeLabel_1;
                    app.hand.show.show1.dateLabel = app.DateLabel_1;
                    app.hand.show.show1.lamp = app.Lamp_1;
                    app.hand.show.show1.predCodeLabel = app.PredCodeLabel_1;
                    app.hand.show.show1.realTimeLabel = app.RTimeLabel_1;
                    app.hand.show.show1.rtlamp = app.RTLamp_1;
                    res = app.hand.show.show1;
                case 2
                    app.hand.show.show2.ax = app.UIAxes_2;
                    app.hand.show.show2.nameLabel = app.NameLabel_2;
                    app.hand.show.show2.codeLabel = app.CodeLabel_2;
                    app.hand.show.show2.dateLabel = app.DateLabel_2;
                    app.hand.show.show2.lamp = app.Lamp_2;
                    app.hand.show.show2.predCodeLabel = app.PredCodeLabel_2;
                    app.hand.show.show2.realTimeLabel = app.RTimeLabel_2;
                    app.hand.show.show2.rtlamp = app.RTLamp_2;
                    res = app.hand.show.show2;
                case 3
                    app.hand.show.show3.ax = app.UIAxes_3;
                    app.hand.show.show3.nameLabel = app.NameLabel_3;
                    app.hand.show.show3.codeLabel = app.CodeLabel_3;
                    app.hand.show.show3.dateLabel = app.DateLabel_3;
                    app.hand.show.show3.lamp = app.Lamp_3;
                    app.hand.show.show3.predCodeLabel = app.PredCodeLabel_3;
                    app.hand.show.show3.realTimeLabel = app.RTimeLabel_3;
                    app.hand.show.show3.rtlamp = app.RTLamp_3;
                    res = app.hand.show.show3;
                case 4
                    app.hand.show.show4.ax = app.UIAxes_4;
                    app.hand.show.show4.nameLabel = app.NameLabel_4;
                    app.hand.show.show4.codeLabel = app.CodeLabel_4;
                    app.hand.show.show4.dateLabel = app.DateLabel_4;
                    app.hand.show.show4.lamp = app.Lamp_4;
                    app.hand.show.show4.predCodeLabel = app.PredCodeLabel_4;
                    app.hand.show.show4.realTimeLabel = app.RTimeLabel_4;
                    app.hand.show.show4.rtlamp = app.RTLamp_4;
                    res = app.hand.show.show4;
                case 5
                    app.hand.show.show5.ax = app.UIAxes_5;
                    app.hand.show.show5.nameLabel = app.NameLabel_5;
                    app.hand.show.show5.codeLabel = app.CodeLabel_5;
                    app.hand.show.show5.dateLabel = app.DateLabel_5;
                    app.hand.show.show5.lamp = app.Lamp_5;
                    app.hand.show.show5.predCodeLabel = app.PredCodeLabel_5;
                    app.hand.show.show5.realTimeLabel = app.RTimeLabel_5;
                    app.hand.show.show5.rtlamp = app.RTLamp_5;
                    res = app.hand.show.show5;
                case 6
                    app.hand.show.show6.ax = app.UIAxes_6;
                    app.hand.show.show6.nameLabel = app.NameLabel_6;
                    app.hand.show.show6.codeLabel = app.CodeLabel_6;
                    app.hand.show.show6.dateLabel = app.DateLabel_6;
                    app.hand.show.show6.lamp = app.Lamp_6;
                    app.hand.show.show6.predCodeLabel = app.PredCodeLabel_6;
                    app.hand.show.show6.realTimeLabel = app.RTimeLabel_6;
                    app.hand.show.show6.rtlamp = app.RTLamp_6;
                    res = app.hand.show.show6;
                case 7
                    app.hand.show.show7.ax = app.UIAxes_7;
                    app.hand.show.show7.nameLabel = app.NameLabel_7;
                    app.hand.show.show7.codeLabel = app.CodeLabel_7;
                    app.hand.show.show7.dateLabel = app.DateLabel_7;
                    app.hand.show.show7.lamp = app.Lamp_7;
                    app.hand.show.show7.predCodeLabel = app.PredCodeLabel_7;
                    app.hand.show.show7.realTimeLabel = app.RTimeLabel_7;
                    app.hand.show.show7.rtlamp = app.RTLamp_7;
                    res = app.hand.show.show7;
                case 8
                    app.hand.show.show8.ax = app.UIAxes_8;
                    app.hand.show.show8.nameLabel = app.NameLabel_8;
                    app.hand.show.show8.codeLabel = app.CodeLabel_8;
                    app.hand.show.show8.dateLabel = app.DateLabel_8;
                    app.hand.show.show8.lamp = app.Lamp_8;
                    app.hand.show.show8.predCodeLabel = app.PredCodeLabel_8;
                    app.hand.show.show8.realTimeLabel = app.RTimeLabel_8;
                    app.hand.show.show8.rtlamp = app.RTLamp_8;
                    res = app.hand.show.show8;
                case 9
                    app.hand.show.show9.ax = app.UIAxes_9;
                    app.hand.show.show9.nameLabel = app.NameLabel_9;
                    app.hand.show.show9.codeLabel = app.CodeLabel_9;
                    app.hand.show.show9.dateLabel = app.DateLabel_9;
                    app.hand.show.show9.lamp = app.Lamp_9;
                    app.hand.show.show9.predCodeLabel = app.PredCodeLabel_9;
                    app.hand.show.show9.realTimeLabel = app.RTimeLabel_9;
                    app.hand.show.show9.rtlamp = app.RTLamp_9;
                    res = app.hand.show.show9;
                case 10
                    app.hand.show.show10.ax = app.UIAxes_10;
                    app.hand.show.show10.nameLabel = app.NameLabel_10;
                    app.hand.show.show10.codeLabel = app.CodeLabel_10;
                    app.hand.show.show10.dateLabel = app.DateLabel_10;
                    app.hand.show.show10.lamp = app.Lamp_10;
                    app.hand.show.show10.predCodeLabel = app.PredCodeLabel_10;
                    app.hand.show.show10.realTimeLabel = app.RTimeLabel_10;
                    app.hand.show.show10.rtlamp = app.RTLamp_10;
                    res = app.hand.show.show10;
            end
        end
        
        function setshow(app,show,index)
            switch index
                case 1
                    app.hand.show.show1 = show;
                case 2
                    app.hand.show.show2 = show;
                case 3
                    app.hand.show.show3 = show;
                case 4
                    app.hand.show.show4 = show;
                case 5
                    app.hand.show.show5 = show;
                case 6
                    app.hand.show.show6 = show;
                case 7
                    app.hand.show.show7 = show;
                case 8
                    app.hand.show.show8 = show;
                case 9
                    app.hand.show.show9 = show;
                case 10
                    app.hand.show.show10 = show;
            end 
        end
        
        function putinlist(app,list,stockcode)
            itemsdata = [];
            items = {};
            for i = 1 : numel(stockcode)
                index = find(strcmp(app.hand.stocklist.code,stockcode{i}));
                index = index(1);
                if isempty(index)
                    itemsdata = [itemsdata -i];
                    items = [items ['[' stockcode{i} ']  ' '????????' ' (' '??' ')  < ' '????????' ' >' ]];
                else
                    itemsdata = [itemsdata index];
                    items = [items ['[' app.hand.stocklist.code{index} ']  ' app.hand.stocklist.name{index} ' (' app.hand.stocklist.shsz{index} ')  < ' app.hand.stocklist.industry{index} ' >' ]];
                end
            end
            if strcmp(list,'kms')
                set(app.KMSList,'items',items);
                set(app.KMSList,'itemsdata',itemsdata);
                app.hand.kmslist.items = items;
                app.hand.kmslist.itemsdata = itemsdata;
            elseif strcmp(list,'cs')
                set(app.CSList,'items',items);
                set(app.CSList,'itemsdata',itemsdata);
                app.hand.cslist.items = items;
                app.hand.cslist.itemsdata = itemsdata;
            end
            
        end
        
        function setProgressbar2(app,val)
             % 设置进度条进度值 
            progress = ceil(102 * val);
            position = get(app.Progressbar2,'position');
            position(3) = progress;
            set(app.Progressbar2,'position',position);
            set(app.ProgressLabel2,'text',[num2str(round(val*100)) ' %']);
            drawnow();
        end
        
        function callback2(app,traininfo)
            try
                app.setProgressbar2(traininfo.Epoch / 500);
            catch
            end
        end
        
        function updatesum(app)
            set(app.TotalSumEditField,'value',app.sum.total);
            set(app.TotalIncomeEditField,'value',app.sum.income);
            set(app.InvestSumEditField,'value',app.sum.invest);
            set(app.ReturnSumEditField,'value',app.sum.return);
            set(app.InitialSumEditField,'value',app.sum.initial);
            set(app.AccountSumEditField,'value',app.sum.account);
        end
        
        function syncdata(app)
            disp('syncdata : main');
            sumtable = get(app.SumTable,'data');
            if isempty(sumtable)
                set(app.SellTable,'data',{});
                return;
            end
            ps = cell2mat(sumtable(:,5:6));
            if isempty(ps)
                set(app.SellTable,'data',{});
                return;
            end
            amp = (ps(:,1)-ps(:,2))*100./ps(:,2);
            temptable = [sumtable(:,1:6) num2cell(amp) sumtable(:,8)];
            selltable = {};
            for i = 1 : numel(temptable,1)
                if ~strcmp(char(datetime(sumtable{i,7},'format','yyyyMMdd')),char(datetime('now','format','yyyyMMdd')))
                    selltable =[selltable; temptable];
                end
            end
            selltable = [selltable num2cell(ones(size(selltable,1),1)) cell(size(selltable,1),1)];
            set(app.SellTable,'data',selltable);
            
            if get(app.AutoSellButton,'value')
                disp('syncdata : fork');
                
                thamp = get(app.ThresholdEditField,'value');
                indexs = find(amp > thamp);
                if isempty(indexs)
                    return;
                end
                tosell = selltable(indexs,:);
                returnsum = 0;
                for i = 1 : size(tosell,1)
                    serial = tosell{i,1};
                    price = tosell{i,5};
                    bill = tosell{i,8};
                    returnsum = returnsum + price * bill * 100;
                    
                    serindex = find(strcmp(sumtable(:,1),serial));
                    sumtable{serindex,8} = sumtable{serindex,8} - bill;
                end
                keepindex = [];
                for i = 1 : size(sumtable,1)
                    if sumtable{i,8} > 0
                        keepindex = [keepindex i];
                    end
                end
                sumtable = sumtable(keepindex,:);
                set(app.SumTable,'data',sumtable);
                app.sum.return = app.sum.return + returnsum;
                app.sum.account = app.sum.account + returnsum;
                app.updatesum();
                app.syncdata();
            end
        end
        
        function localize(app,sumtable,sum)
            save('srcDEMO/sum.mat','sumtable','sum');
        end
        
        function funcback(app,total,current)
            app.setProgressbar(current / total);
        end
        
        function funcback2(app,total,current)
             try
                app.setProgressbar2(current / total);
            catch
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'DEMO';
            app.hand.show = struct();
            
            % 初始化坐标系
            for i = 1 : 10
                app.initshow(i);
            end      
            
            % 初始化进度条
            position = get(app.Progressbar,'position');
            position(3) = 0;
            set(app.Progressbar,'position',position);
            position2 = get(app.Progressbar2,'position');
            position2(3) = 0;
            set(app.Progressbar2,'position',position2);
            
            % 初始化列
            app.hand.keystock = load('src/keystock.mat');
            app.hand.csstock = load('src/csstock.mat');
            para = load('src/stocklist.mat');
            app.hand.stocklist = para.stocklist;
            stocklistitems = cell(1,numel(app.hand.stocklist.code));
            stocklistitemsdata = 1:numel(app.hand.stocklist.code);
            for i = 1 : numel(app.hand.stocklist.code)
                stocklistitems{i} = ['[' app.hand.stocklist.code{i} ']  ' app.hand.stocklist.name{i} ' (' app.hand.stocklist.shsz{i} ')  < ' app.hand.stocklist.industry{i} ' >' ];
            end
            set(app.StockList,'items',stocklistitems);
            set(app.StockList,'itemsData',stocklistitemsdata);
            app.hand.stklist.items = stocklistitems;
            app.hand.stklist.itemsdata = stocklistitemsdata;
            
            set(app.CSList,'items',{});
            set(app.CSList,'itemsData',{});
            app.hand.cslist.items = {};
            app.hand.cslist.itemsdata = [];
            
            app.putinlist('kms',app.hand.keystock.stockcode);
            app.putinlist('cs',app.hand.csstock.stockcode);

            
            set(app.StockPoolList,'items',get(app.CSList,'items'));
            set(app.StockPoolList,'itemsdata',get(app.CSList,'itemsdata'));


            app.hand.poolindex = 1;
%             [rtpix rtmap] = imread('http://image.sinajs.cn/newchart/min/n/sh601006.gif');
%             [dkpix dkmap] = imread('http://image.sinajs.cn/newchart/daily/n/sh601006.gif');
%             imshow(rtpix,rtmap,'parent',app.RealTimeGIF);
%             imshow(dkpix,dkmap,'parent',app.DayKLineGIF);

            sumdata = load('srcDEMO/sum.mat');
            
            app.sum = sumdata.sum;
            set(app.SumTable,'data',sumdata.sumtable);
            app.syncdata();
            app.updatesum();
        end

        % Value changed function: RealTimeButton
        function RealTimeButtonValueChanged(app, event)
            while app.RealTimeButton.Value
                stockinfo = dz_getrealtimewebstockdata(app.hand.keystock.stockcode);
                for i = 1 : numel(stockinfo)
                    show = app.getshow(i);
                    set(show.rtlamp,'color',[1,0,0]); 
                    istockinfo = stockinfo{i};
                    iprice = str2double(istockinfo.currentprice);
                    ilow = str2double(istockinfo.lowprice);
                    ihigh = str2double(istockinfo.highprice);
                    set(show.mark,'ydata',iprice);
                    set(show.rect,'position',[0.5 ilow 1 ihigh-ilow]);
%                     set(show.nameLabel,'text',istockinfo.stockname,'fontcolor',[1,0,0]);
                    set(show.codeLabel,'text',istockinfo.stockcode,'fontcolor',[1,0,0]);
                    set(show.realTimeLabel,'text',istockinfo.time,'fontcolor',[1,0,0]); 
                    
                    drawnow
                    pause(0.2);
                    app.setProgressbar(i/numel(stockinfo));
                    
%                     set(show.nameLabel,'text',istockinfo.stockname,'fontcolor',[0,1,0]);
                    set(show.codeLabel,'text',istockinfo.stockcode,'fontcolor',[0,0,0]);
                    set(show.realTimeLabel,'text',istockinfo.time,'fontcolor',[0,0,0]); 
                    set(show.rtlamp,'color',[0,1,0]); 
                end
                pause(5);
            end
        end

        % Button pushed function: PredictionButton
        function PredictionButtonPushed(app, event)

            app.hand.preddata = load('src/preddata.mat');
            lastpredday = app.hand.preddata.time;
            lastpredkeyid = app.hand.preddata.keyid;
            % 判断即将预测的数据的时间
            webstockdata = dz_getwebstockdata('601155','20191212',char(datetime('yesterday','format','yyyyMMdd')-days(1)));
            predday = dz_nextworkday(datetime(webstockdata.date{end}),2,'yyyyMMdd');
            if strcmp(char(predday(end)),lastpredday) && strcmp(app.hand.keystock.keyid,lastpredkeyid)
                value = app.hand.preddata.value; 
                for i = 1 : size(value,1)
                    show = app.getshow(i);
                    set(show.lamp,'color',[1 0 0]);
                    set(show.line1,'ydata',[value(i,1) value(i,1)]);
                    set(show.line2,'ydata',[value(i,2) value(i,2)]);
                    set(show.lamp,'color',[0 0 0]);
                    set(show.dateLabel,'text',lastpredday);
                    set(show.predCodeLabel,'text',app.hand.preddata.stockcode{i});
                    set(show.lamp,'color',[0 1 0]);
                    drawnow
                end
                if ~get(app.RePredCheckBox,'value')
                    return;
                end
            end
            
            value = zeros(numel(app.hand.keystock.stockcode),2);
            time = '';
            for i = 1 : numel(app.hand.keystock.stockcode)
                show = app.getshow(i);
                set(show.lamp,'color',[1 0 0]);
                [cred,predstockdata] = ddz_stockpred(app.hand.keystock.stockcode{i},@app.callback,true);
                prices = dz_getfeats(predstockdata,'low','high');
                low = prices(1,2);
                high = prices(2,2);
                value(i,1) = low;
                value(i,2) = high;
                set(show.line1,'ydata',[low low]);
                set(show.line2,'ydata',[high high]);
                set(show.dateLabel,'text',predstockdata.date{2});
                set(show.predCodeLabel,'text',app.hand.keystock.stockcode{i});
                set(show.lamp,'color',[0 1 0]);
                time = char(datetime(predstockdata.date{2},'format','yyyyMMdd'));
                drawnow
            end
            keyid = app.hand.keystock.keyid;
            stockcode = app.hand.keystock.stockcode;
            save('src/preddata.mat','keyid','time','stockcode','value');
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            keyword = get(app.SearchField,'value');
            if numel(keyword)
                searchedlist = {};
                searcheddata = [];
                for i = 1 : numel(app.hand.stklist.items)
                    if ~isempty(regexp(app.hand.stklist.items{i},keyword))
                        searchedlist = [searchedlist app.hand.stklist.items{i}];
                        searcheddata = [searcheddata i];
                    end
                end
                set(app.StockList,'items',searchedlist);
                set(app.StockList,'itemsData',searcheddata);
            else
                set(app.StockList,'items',app.hand.stklist.items);
                set(app.StockList,'itemsData',app.hand.stklist.itemsdata);
            end
        end

        % Button pushed function: AddCSButton
        function AddCSButtonPushed(app, event)
            stockid = get(app.StockList,'value');
            if ismember(stockid,app.hand.cslist.itemsdata)
                return;
            end
            app.hand.cslist.itemsdata = [app.hand.cslist.itemsdata stockid];
            app.hand.cslist.items = [app.hand.cslist.items app.hand.stklist.items{stockid}];
            set(app.CSList,'items',app.hand.cslist.items);
            set(app.CSList,'itemsdata',app.hand.cslist.itemsdata);
        end

        % Button pushed function: AddKMSButton
        function AddKMSButtonPushed(app, event)
            if numel(get(app.KMSList,'itemsdata')) >= 10
               return; 
            end
            stockid = get(app.StockList,'value');
            if ismember(stockid,app.hand.kmslist.itemsdata)
                return;
            end
            app.hand.kmslist.itemsdata = [app.hand.kmslist.itemsdata stockid];
            app.hand.kmslist.items = [app.hand.kmslist.items app.hand.stklist.items{stockid}];
            set(app.KMSList,'items',app.hand.kmslist.items);
            set(app.KMSList,'itemsdata',app.hand.kmslist.itemsdata);
            if numel(get(app.KMSList,'itemsdata')) >= 10
               set(app.StartButton,'enable','on');
            else
               set(app.StartButton,'enable','off');
            end 
        end

        % Button pushed function: RemoveCSButton
        function RemoveCSButtonPushed(app, event)
            stockid = get(app.CSList,'value');
            if isempty(stockid)
                return;
            end
            index = find(app.hand.cslist.itemsdata==stockid);
            app.hand.cslist.itemsdata(index) = [];
            app.hand.cslist.items(index) = [];
            set(app.CSList,'itemsdata',app.hand.cslist.itemsdata);
            set(app.CSList,'items',app.hand.cslist.items);
        end

        % Button pushed function: RemoveKMSButton
        function RemoveKMSButtonPushed(app, event)
            stockid = get(app.KMSList,'value');
            if isempty(stockid)
                return;
            end
            index = find(app.hand.kmslist.itemsdata==stockid);
            app.hand.kmslist.itemsdata(index) = [];
            app.hand.kmslist.items(index) = [];
            set(app.KMSList,'itemsdata',app.hand.kmslist.itemsdata);
            set(app.KMSList,'items',app.hand.kmslist.items);
            if numel(app.KMSList,'itemsdata') >= 10
               set(app.StartButton,'enable','on');
            else
               set(app.StartButton,'enable','off');
            end 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            stockid = get(app.KMSList,'itemsdata');
            stockcode = app.hand.stocklist.code(stockid);
            keyid = char(datetime);
            save('src/keystock.mat','stockcode','keyid');
            app.hand.keystock.stockcode = stockcode;
            app.hand.keystock.keyid = keyid;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            set(app.StockPoolList,'items',get(app.CSList,'items'));
            set(app.StockPoolList,'itemsdata',get(app.CSList,'itemsdata'));
            stockid = get(app.CSList,'itemsdata');
            stockcode = app.hand.stocklist.code(stockid);
            keyid = char(datetime);
            save('src/csstock.mat','stockcode','keyid');
            app.hand.csstock.stockcode = stockcode;
            app.hand.csstock.keyid = keyid;
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
           app.ClearCSButton();
           set(app.CSList,'items',get(app.StockList,'items'));
           set(app.CSList,'itemsdata',get(app.StockList,'itemsdata'));
           app.hand.cslist.items = get(app.StockList,'items');
           app.hand.cslist.itemsdata = get(app.StockList,'itemsdata');
        end

        % Button pushed function: ClearCSButton
        function ClearCSButtonPushed(app, event)
            set(app.CSList,'items',{});
            set(app.CSList,'itemsdata',[]);
            app.hand.cslist.items = {};
            app.hand.cslist.itemsdata = [];
        end

        % Button pushed function: ClearKMSButton
        function ClearKMSButtonPushed(app, event)
            set(app.KMSList,'items',{});
            set(app.KMSList,'itemsdata',[]);
            app.hand.kmslist.items = {};
            app.hand.kmslist.itemsdata = [];
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            index = event.Indices;
            if index(2) == 1
                data = get(app.UITable,'data');
                if strcmp(data(index(1),1),'')
                    data{index(1),1} = '√';
                else
                    data{index(1),1} = '';
                end
                set(app.UITable,'data',data);
            end
        end

        % Button pushed function: AddSuggestionsButton
        function AddSuggestionsButtonPushed(app, event)
            tabledata = get(app.UITable,'data');
            index = [];
            for i = 1 : size(tabledata,1)
                if strcmp(tabledata{i,1},'√')
                    index = [index i];
                end
            end
            if ~isempty(index)
                app.ClearButtonPushed();
                datacell = get(app.UITable,'data');
                datacell = datacell(index,:);
                datacell(:,8) = [];
                datacell(:,6) = [];
                datacell(:,1) = [];
                datacell = [datacell num2cell(ones(size(datacell,1),1))];
                set(app.BuyTable,'data',datacell);
            end
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            stockid = get(app.PurchaseCartTable,'value');
            if isempty(stockid)
                return;
            end
            itemsdata = get(app.PurchaseCartTable,'itemsdata');
            items = get(app.PurchaseCartTable,'items');
            index = find(itemsdata==stockid);
            itemsdata(index) = [];
            items(index) = [];
            set(app.PurchaseCartTable,'itemsdata',itemsdata);
            set(app.PurchaseCartTable,'items',items);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            set(app.BuyTable,'data',{});
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            while app.RunButton.Value
                nextindex = app.hand.poolindex;
                itemsdata = get(app.StockPoolList,'itemsdata');
                if nextindex > numel(itemsdata)
                    set(app.RunButton,'value',false);
                    return;
                end
                istockid = itemsdata(nextindex);
                set(app.StockPoolList,'value',istockid);
                scroll(app.StockPoolList,istockid);
                
                istockcode = app.hand.stocklist.code{istockid};
                try
                    [cred,predstockdata] = ddz_stockpred(istockcode,@app.callback2,true);
                catch
                    app.hand.poolindex = app.hand.poolindex + 1;
                    continue;
                end
                if isempty(predstockdata) || cred < 0.5
                    app.hand.poolindex = app.hand.poolindex + 1;
                    continue;
                end
                prices = dz_getfeats(predstockdata,'low','high');
                low = prices(1,2);
                high = prices(2,2);
                preddate = predstockdata.date{2};
                istockinfo = ddz_getwebstockdataAtyesterday(istockcode);
                if isempty(istockinfo)
                    app.hand.poolindex = app.hand.poolindex + 1;
                    continue;
                end
                cprice = (istockinfo.highprice + istockinfo.lowprice)/2;
                fprintf(['cp: ' num2str(cprice) '  low: ' num2str(low) '\n']);
                if low >= cprice
                    datacell = get(app.UITable,'data');
                    itemexist = false;
                    for i = 1 : size(datacell,1)
                        if istockid == datacell{i,2}
                            datacell(i,:) = {'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,num2str((low-cprice)*100/cprice),preddate};
                            itemexist = true;
                            break;
                        end
                    end
                    if ~itemexist
                        datacell = [datacell;{'√',istockid,app.hand.stocklist.name{istockid},app.hand.stocklist.code{istockid},cprice,low,num2str((low-cprice)*100/cprice),preddate}];
                    end
                    set(app.UITable,'data',datacell);
                end
                app.hand.poolindex = app.hand.poolindex + 1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~get(app.UnLockButton,'value')
                return;
            end
            app.hand.poolindex = 0;
            set(app.UnLockButton,'value',false);
        end

        % Button pushed function: PurchaseButton
        function PurchaseButtonPushed(app, event)
            cost = 0;
            buytable = {};
            tabledata = get(app.BuyTable,'data');
            for i = 1 : size(tabledata,1)
                data = tabledata(i,:);
                buydata = {[char(datetime('now','format','yyyyMMddHHmmss')) num2str(i)] data{1:4} data{4} char(datetime()) data{6} 0};
                buytable = [buytable;buydata];
                cost = cost + data{4} * data{6} * 100;
            end
            sumtable = get(app.SumTable,'data');
            set(app.SumTable,'data',[sumtable;buytable]);
            app.sum.invest = app.sum.invest + cost;
            app.sum.account = app.sum.account - cost;
            app.updatesum();
            
            app.syncdata();
        end

        % Cell selection callback: SellTable
        function SellTableCellSelection(app, event)
            index = event.Indices;
            if index(2) == 10
                data = get(app.SellTable,'data');
                if strcmp(data(index(1),10),'')
                    data{index(1),10} = '√';
                else
                    data{index(1),10} = '';
                end
                set(app.SellTable,'data',data);
            end
        end

        % Button pushed function: SellButton
        function SellButtonPushed(app, event)
            set(app.TradeMonitorSwitch,'value','Off');
            pause(5);
            selltable = get(app.SellTable,'data');
            sumtable = get(app.SumTable,'data');
            index = [];
            for i = 1 : size(selltable,1)
                if strcmp(selltable{i,10},'√') && selltable{i,9}<=selltable{i,8}
                    index = [index i];
                end
            end
            tosell = selltable(index,:);
            returnsum = 0;
            for i = 1 : size(tosell,1)
                serial = tosell{i,1};
                price = tosell{i,5};
                bill = tosell{i,9};
                returnsum = returnsum + price * bill * 100;
                
                serindex = find(strcmp(sumtable(:,1),serial));
                sumtable{serindex,8} = sumtable{serindex,8} - bill;
            end
            keepindex = [];
            for i = 1 : size(sumtable,1)
                if sumtable{i,8} > 0
                    keepindex = [keepindex i];
                end
            end
            sumtable = sumtable(keepindex,:);
            set(app.SumTable,'data',sumtable);
            app.sum.return = app.sum.return + returnsum;
            app.sum.account = app.sum.account + returnsum;
            app.updatesum();
            app.syncdata();
            set(app.TradeMonitorSwitch,'value','On');
            app.TradeMonitorSwitchValueChanged();
        end

        % Value changed function: TradeMonitorSwitch
        function TradeMonitorSwitchValueChanged(app, event)
            while strcmp(app.TradeMonitorSwitch.Value,'On')
                
                sumtable = get(app.SumTable,'data');
                
                if ~isempty(sumtable)
                    numstocks = size(sumtable,1);
                    numincrease = 0;
                    sellsum = 0;
                    for i = 1 : size(sumtable,1)
                        code = sumtable{i,4};
                        try
                            stockinfo = dz_getrealtimewebstockdata(code);
                        catch
                            continue;
                        end
                        price = str2double(stockinfo.currentprice);
                        sumtable{i,5} = price;
                        sumtable{i,9} = (price - sumtable{i,6}) * sumtable{i,8} * 100;
                        if price > sumtable{i,6}
                            numincrease = numincrease + 1;
                        end
                        set(app.SumTable,'data',sumtable);
                        
                        sellsum = sellsum +  price * sumtable{i,8} * 100;
                        pause(0.05);
                    end
                    app.sum.total = app.sum.account + sellsum;
                    app.sum.income = app.sum.total - app.sum.initial;
                    app.updatesum();
                    app.syncdata();
                    
                    set(app.IncreaseLabel,'text',[num2str(numincrease) ' / ' num2str(numstocks)]);
                    set(app.IncreaseRateLabel,'text',[num2str(round(numincrease*100/numstocks,1)) ' %']);
                end
                
                
                app.localize(sumtable,app.sum);
                
                
                pause(2);
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            if ~get(app.UnLockButton,'value')
                return;
            end
            sum.total = 1000000;
            sum.account = 1000000;
            sum.income = 0;
            sum.invest = 0;
            sum.return = 0;
            sum.initial = 1000000;
            app.sum = sum;
            app.updatesum();
            set(app.SumTable,'data',{});
            app.syncdata();
            app.localize({},sum);
            set(app.UnLockButton,'value',false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RealTimeMonitorTab = uitab(app.TabGroup);
            app.RealTimeMonitorTab.Title = 'Real Time Monitor';
            app.UIAxes_1 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_2 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_3 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_4 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_5 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_6 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_7 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_8 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_9 = uiaxes(app.RealTimeMonitorTab);
            app.UIAxes_10 = uiaxes(app.RealTimeMonitorTab);
            app.Lamp_1 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_2 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_3 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_4 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_5 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_6 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_7 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_8 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_9 = uilamp(app.RealTimeMonitorTab);
            app.Lamp_10 = uilamp(app.RealTimeMonitorTab);
            app.NameLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_1.Text = '--';
            app.NameLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_2.Text = '--';
            app.NameLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_3.Text = '--';
            app.NameLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_4.Text = '--';
            app.NameLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_5.Text = '--';
            app.NameLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_6.Text = '--';
            app.NameLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_7.Text = '--';
            app.NameLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_8.Text = '--';
            app.NameLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_9.Text = '--';
            app.NameLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.NameLabel_10.Text = '--';
            app.DateLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_1.Text = '--';
            app.DateLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_2.Text = '--';
            app.DateLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_3.Text = '--';
            app.DateLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_4.Text = '--';
            app.DateLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_5.Text = '--';
            app.DateLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_6.Text = '--';
            app.DateLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_7.Text = '--';
            app.DateLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_8.Text = '--';
            app.DateLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_9.Text = '--';
            app.DateLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.DateLabel_10.Text = '--';
            app.CodeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_1.Text = '--';
            app.CodeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_2.Text = '--';
            app.CodeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_3.Text = '--';
            app.CodeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_4.Text = '--';
            app.CodeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_5.Text = '--';
            app.CodeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_6.Text = '--';
            app.CodeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_7.Text = '--';
            app.CodeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_8.Text = '--';
            app.CodeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_9.Text = '--';
            app.CodeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.CodeLabel_10.Text = '--';
            app.Panel = uipanel(app.RealTimeMonitorTab);
            app.RealTimeButton = uibutton(app.Panel, 'state');
            app.RealTimeButton.ValueChangedFcn = createCallbackFcn(app, @RealTimeButtonValueChanged, true);
            app.RealTimeButton.Text = 'Real Time';
            app.PredictionButton = uibutton(app.Panel, 'push');
            app.PredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionButtonPushed, true);
            app.PredictionButton.Text = 'Prediction';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.RePredCheckBox = uicheckbox(app.Panel);
            app.RePredCheckBox.Text = 'RePred';
            app.Progressbar_2 = uilabel(app.Panel);
            app.Progressbar_2.Text = '';
            app.Progressbar = uilabel(app.Panel);
            app.Progressbar.Text = '';
            app.PredCodeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_1.Text = '--';
            app.PredCodeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_2.Text = '--';
            app.PredCodeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_3.Text = '--';
            app.PredCodeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_4.Text = '--';
            app.PredCodeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_5.Text = '--';
            app.PredCodeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_6.Text = '--';
            app.PredCodeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_7.Text = '--';
            app.PredCodeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_8.Text = '--';
            app.PredCodeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_9.Text = '--';
            app.PredCodeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.PredCodeLabel_10.Text = '--';
            app.RTimeLabel_1 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_1.Text = '--';
            app.RTimeLabel_2 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_2.Text = '--';
            app.RTimeLabel_3 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_3.Text = '--';
            app.RTimeLabel_4 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_4.Text = '--';
            app.RTimeLabel_5 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_5.Text = '--';
            app.RTimeLabel_6 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_6.Text = '--';
            app.RTimeLabel_7 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_7.Text = '--';
            app.RTimeLabel_8 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_8.Text = '--';
            app.RTimeLabel_9 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_9.Text = '--';
            app.RTimeLabel_10 = uilabel(app.RealTimeMonitorTab);
            app.RTimeLabel_10.Text = '--';
            app.RTLamp_1 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_2 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_3 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_4 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_5 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_6 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_7 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_8 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_9 = uilamp(app.RealTimeMonitorTab);
            app.RTLamp_10 = uilamp(app.RealTimeMonitorTab);
            app.StockListTab = uitab(app.TabGroup);
            app.StockListTab.Title = 'Stock List';
            app.StockList = uilistbox(app.StockListTab);
            app.StockList.Items = {};
            app.StockList.Value = {};
            app.AddCSButton = uibutton(app.StockListTab, 'push');
            app.AddCSButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSButtonPushed, true);
            app.AddCSButton.Text = '>>>';
            app.CSList = uilistbox(app.StockListTab);
            app.CSList.Items = {};
            app.CSList.Value = {};
            app.RemoveCSButton = uibutton(app.StockListTab, 'push');
            app.RemoveCSButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCSButtonPushed, true);
            app.RemoveCSButton.Text = '<<<';
            app.KMSList = uilistbox(app.StockListTab);
            app.KMSList.Items = {};
            app.KMSList.Value = {};
            app.AddKMSButton = uibutton(app.StockListTab, 'push');
            app.AddKMSButton.ButtonPushedFcn = createCallbackFcn(app, @AddKMSButtonPushed, true);
            app.AddKMSButton.Text = '>>>';
            app.RemoveKMSButton = uibutton(app.StockListTab, 'push');
            app.RemoveKMSButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveKMSButtonPushed, true);
            app.RemoveKMSButton.Text = '<<<';
            app.SearchField = uieditfield(app.StockListTab, 'text');
            app.SearchButton = uibutton(app.StockListTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.CSLabel = uilabel(app.StockListTab);
            app.CSLabel.Text = 'Candidates Stocks';
            app.KMSLabel = uilabel(app.StockListTab);
            app.KMSLabel.Text = 'Key Monitor Stocks';
            app.RealTimeGIF = uiaxes(app.StockListTab);
            app.DayKLineGIF = uiaxes(app.StockListTab);
            app.StartButton = uibutton(app.StockListTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.StockListTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ALLButton = uibutton(app.StockListTab, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.ClearKMSButton = uibutton(app.StockListTab, 'push');
            app.ClearKMSButton.ButtonPushedFcn = createCallbackFcn(app, @ClearKMSButtonPushed, true);
            app.ClearKMSButton.Text = 'Clear';
            app.ClearCSButton = uibutton(app.StockListTab, 'push');
            app.ClearCSButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCSButtonPushed, true);
            app.ClearCSButton.Text = 'Clear';
            app.RecommenderSystemTab = uitab(app.TabGroup);
            app.RecommenderSystemTab.Title = 'Recommender System';
            app.StockPoolList = uilistbox(app.RecommenderSystemTab);
            app.StockPoolList.Items = {};
            app.StockPoolList.Value = {};
            app.UITable = uitable(app.RecommenderSystemTab);
            app.UITable.ColumnName = {'S'; 'ID'; 'Name'; 'Code'; 'Price'; 'Pred Low Price'; 'Amplitude (%)'; 'Pred Date'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StockPoolLabel = uilabel(app.RecommenderSystemTab);
            app.StockPoolLabel.Text = 'Stock Pool';
            app.SuggestionLabel = uilabel(app.RecommenderSystemTab);
            app.SuggestionLabel.Text = 'Suggestion';
            app.Label_2 = uilabel(app.RecommenderSystemTab);
            app.Label_2.Text = '';
            app.ProgressLabel2 = uilabel(app.RecommenderSystemTab);
            app.ProgressLabel2.Text = '%';
            app.Progressbar2 = uilabel(app.RecommenderSystemTab);
            app.Progressbar2.Text = '';
            app.RunButton = uibutton(app.RecommenderSystemTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run ▶';
            app.ResetButton = uibutton(app.RecommenderSystemTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TradeSimulationSystemTab = uitab(app.TabGroup);
            app.TradeSimulationSystemTab.Title = 'Trade Simulation System';
            app.TabGroup2 = uitabgroup(app.TradeSimulationSystemTab);
            app.InTab = uitab(app.TabGroup2);
            app.InTab.Title = 'In';
            app.AddSuggestionsButton = uibutton(app.InTab, 'push');
            app.AddSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddSuggestionsButtonPushed, true);
            app.AddSuggestionsButton.Text = 'Add Suggestions';
            app.ClearButton = uibutton(app.InTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear ';
            app.PurchaseButton = uibutton(app.InTab, 'push');
            app.PurchaseButton.ButtonPushedFcn = createCallbackFcn(app, @PurchaseButtonPushed, true);
            app.PurchaseButton.Text = 'Purchase';
            app.BuyTable = uitable(app.InTab);
            app.BuyTable.ColumnName = {'ID'; 'Name'; 'Code'; 'Price'; 'Amplitude'; 'Bill'};
            app.BuyTable.RowName = {};
            app.BuyTable.ColumnEditable = [false false false false false true];
            app.OutTab = uitab(app.TabGroup2);
            app.OutTab.Title = 'Out';
            app.SellTable = uitable(app.OutTab);
            app.SellTable.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Buy Price'; 'Amplitude (%)'; 'Bill'; 'Sell Bills'; 'S'};
            app.SellTable.RowName = {};
            app.SellTable.ColumnEditable = [false false false false false false false false true false];
            app.SellTable.CellSelectionCallback = createCallbackFcn(app, @SellTableCellSelection, true);
            app.SellButton = uibutton(app.OutTab, 'push');
            app.SellButton.ButtonPushedFcn = createCallbackFcn(app, @SellButtonPushed, true);
            app.SellButton.Text = 'Sell';
            app.AutoSellButton = uibutton(app.OutTab, 'state');
            app.AutoSellButton.Text = 'Auto Sell';
            app.ThresholdEditFieldLabel = uilabel(app.OutTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.OutTab, 'numeric');
            app.Label_3 = uilabel(app.OutTab);
            app.Label_3.Text = '%';
            app.SumTable = uitable(app.TradeSimulationSystemTab);
            app.SumTable.ColumnName = {'Serial'; 'ID'; 'Name'; 'Code'; 'Price'; 'Buy Price'; 'Buy Date'; 'Bill'; 'Income'};
            app.SumTable.RowName = {};
            app.TotalSumEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.TotalSumEditFieldLabel.Text = 'Total Sum';
            app.TotalSumEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.TotalSumEditField.ValueDisplayFormat = '%.2f';
            app.TotalSumEditField.Editable = 'off';
            app.TotalSumEditField.Value = 1000000;
            app.AccountSumEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.AccountSumEditFieldLabel.Text = 'Account Sum';
            app.AccountSumEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.AccountSumEditField.ValueDisplayFormat = '%.2f';
            app.AccountSumEditField.Editable = 'off';
            app.AccountSumEditField.Value = 1000000;
            app.InvestSumEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.InvestSumEditFieldLabel.Text = 'Invest Sum';
            app.InvestSumEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.InvestSumEditField.ValueDisplayFormat = '%.2f';
            app.InvestSumEditField.Editable = 'off';
            app.ReturnSumEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.ReturnSumEditFieldLabel.Text = 'Return Sum';
            app.ReturnSumEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.ReturnSumEditField.ValueDisplayFormat = '%.2f';
            app.ReturnSumEditField.Editable = 'off';
            app.TotalIncomeEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.TotalIncomeEditFieldLabel.Text = 'Total Income';
            app.TotalIncomeEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.TotalIncomeEditField.ValueDisplayFormat = '%.2f';
            app.TotalIncomeEditField.Editable = 'off';
            app.InitialSumEditFieldLabel = uilabel(app.TradeSimulationSystemTab);
            app.InitialSumEditFieldLabel.Text = 'Initial Sum';
            app.InitialSumEditField = uieditfield(app.TradeSimulationSystemTab, 'numeric');
            app.InitialSumEditField.ValueDisplayFormat = '%.2f';
            app.InitialSumEditField.Editable = 'off';
            app.Label99 = uilabel(app.TradeSimulationSystemTab);
            app.Label99.Text = 'Increase : ';
            app.IncreaseLabel = uilabel(app.TradeSimulationSystemTab);
            app.IncreaseLabel.Text = '';
            app.IncreaseRateLabel = uilabel(app.TradeSimulationSystemTab);
            app.IncreaseRateLabel.Text = '';
            app.Label99_2 = uilabel(app.TradeSimulationSystemTab);
            app.Label99_2.Text = 'Accuracy : ';
            app.TradeMonitorSwitchLabel = uilabel(app.UIFigure);
            app.TradeMonitorSwitchLabel.Text = 'Trade Monitor';
            app.TradeMonitorSwitch = uiswitch(app.UIFigure, 'slider');
            app.TradeMonitorSwitch.ValueChangedFcn = createCallbackFcn(app, @TradeMonitorSwitchValueChanged, true);
            app.ResetButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.UnLockButton = uibutton(app.UIFigure, 'state');
            app.UnLockButton.Text = 'UnLock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realtimeerDEMO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end